classdef fragtolerances < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MS2MassTolerancesPanel  matlab.ui.container.Panel
        CIDunits                matlab.ui.control.DropDown
        CIDMS2ToleranceLabel    matlab.ui.control.Label
        CID                     matlab.ui.control.NumericEditField
        HCDunits                matlab.ui.control.DropDown
        HCDMS2ToleranceLabel    matlab.ui.control.Label
        HCD                     matlab.ui.control.NumericEditField
        ETDunits                matlab.ui.control.DropDown
        ETDMS2ToleranceLabel    matlab.ui.control.Label
        ETD                     matlab.ui.control.NumericEditField
        ETciDunits              matlab.ui.control.DropDown
        ETciDMS2ToleranceLabel  matlab.ui.control.Label
        ETciD                   matlab.ui.control.NumericEditField
        EThcDunits              matlab.ui.control.DropDown
        EThcDMS2ToleranceLabel  matlab.ui.control.Label
        EThcD                   matlab.ui.control.NumericEditField
        OKButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, preprocessgui, frag_options)
            app.CallingApp = preprocessgui;
            app.CID.Value = frag_options{1,1};
            app.CIDunits.Value = frag_options{1,2};
            app.HCD.Value = frag_options{2,1};
            app.HCDunits.Value = frag_options{2,2};
            app.ETD.Value = frag_options{3,1};
            app.ETDunits.Value = frag_options{3,2};
            app.ETciD.Value = frag_options{4,1};
            app.ETciDunits.Value = frag_options{4,2};
            app.EThcD.Value = frag_options{5,1};
            app.EThcDunits.Value = frag_options{5,2};
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            cancelfrag(app.CallingApp);
            delete(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Executes when Button "OK" is pushed - Pack all MS2
            %     tolerence information, pass them to parent GUI
            frag_values = {app.CID.Value,app.CIDunits.Value;app.HCD.Value,app.HCDunits.Value;app.ETD.Value,app.ETDunits.Value;...
                           app.ETciD.Value,app.ETciDunits.Value;app.EThcD.Value,app.EThcDunits.Value};
            fragoptions(app.CallingApp,frag_values);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MS2MassTolerancesPanel = uipanel(app.UIFigure);
            app.MS2MassTolerancesPanel.Title = 'MS2 Mass Tolerances';
            app.CIDunits = uidropdown(app.MS2MassTolerancesPanel);
            app.CIDunits.Items = {'ppm', 'Da'};
            app.CIDunits.Value = 'ppm';
            app.CIDMS2ToleranceLabel = uilabel(app.MS2MassTolerancesPanel);
            app.CIDMS2ToleranceLabel.Text = 'CID MS2 Tolerance:';
            app.CID = uieditfield(app.MS2MassTolerancesPanel, 'numeric');
            app.CID.Value = 35;
            app.HCDunits = uidropdown(app.MS2MassTolerancesPanel);
            app.HCDunits.Items = {'ppm', 'Da'};
            app.HCDunits.Value = 'ppm';
            app.HCDMS2ToleranceLabel = uilabel(app.MS2MassTolerancesPanel);
            app.HCDMS2ToleranceLabel.Text = 'HCD MS2 Tolerance:';
            app.HCD = uieditfield(app.MS2MassTolerancesPanel, 'numeric');
            app.HCD.Value = 35;
            app.ETDunits = uidropdown(app.MS2MassTolerancesPanel);
            app.ETDunits.Items = {'ppm', 'Da'};
            app.ETDunits.Value = 'ppm';
            app.ETDMS2ToleranceLabel = uilabel(app.MS2MassTolerancesPanel);
            app.ETDMS2ToleranceLabel.Text = 'ETD MS2 Tolerance:';
            app.ETD = uieditfield(app.MS2MassTolerancesPanel, 'numeric');
            app.ETD.Value = 35;
            app.ETciDunits = uidropdown(app.MS2MassTolerancesPanel);
            app.ETciDunits.Items = {'ppm', 'Da'};
            app.ETciDunits.Value = 'ppm';
            app.ETciDMS2ToleranceLabel = uilabel(app.MS2MassTolerancesPanel);
            app.ETciDMS2ToleranceLabel.Text = 'ETciD MS2 Tolerance:';
            app.ETciD = uieditfield(app.MS2MassTolerancesPanel, 'numeric');
            app.ETciD.Value = 35;
            app.EThcDunits = uidropdown(app.MS2MassTolerancesPanel);
            app.EThcDunits.Items = {'ppm', 'Da'};
            app.EThcDunits.Value = 'ppm';
            app.EThcDMS2ToleranceLabel = uilabel(app.MS2MassTolerancesPanel);
            app.EThcDMS2ToleranceLabel.Text = 'EThcD MS2 Tolerance:';
            app.EThcD = uieditfield(app.MS2MassTolerancesPanel, 'numeric');
            app.EThcD.Value = 35;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fragtolerances(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end