classdef modeloptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModelOptionsUIFigure           matlab.ui.Figure
        AveragineModelParametersPanel  matlab.ui.container.Panel
        AddInfo                        matlab.ui.container.Panel
        SaveOutputAsButton             matlab.ui.control.Button
        OutputFileLocationTextAreaLabel  matlab.ui.control.Label
        OutputFileLocationTextArea     matlab.ui.control.TextArea
        RatioofelementsincompositionmaxmasslimitandmassresolutionPanel  matlab.ui.container.Panel
        CLabel                         matlab.ui.control.Label
        C                              matlab.ui.control.NumericEditField
        HLabel                         matlab.ui.control.Label
        H                              matlab.ui.control.NumericEditField
        NLabel                         matlab.ui.control.Label
        N                              matlab.ui.control.NumericEditField
        OLabel                         matlab.ui.control.Label
        O                              matlab.ui.control.NumericEditField
        SLabel                         matlab.ui.control.Label
        S                              matlab.ui.control.NumericEditField
        MassLimitDaLabel               matlab.ui.control.Label
        MassLimit                      matlab.ui.control.NumericEditField
        ResolutionDaEditFieldLabel     matlab.ui.control.Label
        Res                            matlab.ui.control.NumericEditField
        MassIntervalDaDropDownLabel    matlab.ui.control.Label
        MassInt                        matlab.ui.control.DropDown
        OKButton                       matlab.ui.control.Button
        LoadcustomAveraginemodelPanel  matlab.ui.container.Panel
        SelectDataFileButton           matlab.ui.control.Button
        DataFileLocationTextAreaLabel  matlab.ui.control.Label
        DataFileLocationTextArea       matlab.ui.control.TextArea
    end


    properties (Access = private)
        CallingApp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, preprocessgui, model_options, monoisolocation, monoisoutput)
            app.CallingApp = preprocessgui;
            app.C.Value = model_options(1);
            app.H.Value = model_options(2);
            app.N.Value = model_options(3);
            app.O.Value = model_options(4);
            app.S.Value = model_options(5);
            app.MassLimit.Value = model_options(6);
            app.Res.Value = model_options(7);
            app.MassInt.Value = num2str(model_options(8));
            app.DataFileLocationTextArea.Value = monoisolocation;
            app.OutputFileLocationTextArea.Value = monoisoutput;
        end

        % Close request function: ModelOptionsUIFigure
        function ModelOptionsUIFigureCloseRequest(app, event)
            cancelmodel(app.CallingApp);
            delete(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Pack user inputs then pass to parent GUI
            % If a new model is set, it will be built in the parent GUI,
            %     not here.
            model_options = [app.C.Value,app.H.Value,app.N.Value,...
                             app.O.Value,app.S.Value,app.MassLimit.Value,...
                             app.Res.Value,str2double(app.MassInt.Value)];  % CHNOS contents
            monoisolocation = app.DataFileLocationTextArea.Value;  % Location of selected existing model file
            monoisoutput = app.OutputFileLocationTextArea.Value;  % Location of user saved model file
            averagineoptions(app.CallingApp,model_options,monoisolocation,monoisoutput);
            delete(app)
        end

        % Button pushed function: SelectDataFileButton
        function SelectDataFileButtonPushed(app, event)
            % Executes when Button "Select Data File" is pushed
            [filename,pathname] = uigetfile({'*.mat'},'Select a File');
            app.ModelOptionsUIFigure.Visible = 'off';
            app.ModelOptionsUIFigure.Visible = 'on';
            if(isequal(filename,0) || isequal(pathname,0))
                msgbox('File NOT Selected');
            else
                msfilename = fullfile(pathname,filename);
                app.DataFileLocationTextArea.Value = msfilename;
                app.OutputFileLocationTextArea.Value = '';
            end
        end

        % Button pushed function: SaveOutputAsButton
        function SaveOutputAsButtonPushed(app, event)
            % Executes when Button "Save Output As" is pushed
            selpath = uigetdir(path,'Select Output Location');
            app.ModelOptionsUIFigure.Visible = 'off';
            app.ModelOptionsUIFigure.Visible = 'on';
            if (isequal(selpath,0))
                msgbox('Output Location NOT Selected');
            else
                app.OutputFileLocationTextArea.Value = selpath;
                app.DataFileLocationTextArea.Value = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModelOptionsUIFigure = uifigure('Visible', 'off');
            app.ModelOptionsUIFigure.Name = 'Model Options';
            app.ModelOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ModelOptionsUIFigureCloseRequest, true);
            app.AveragineModelParametersPanel = uipanel(app.ModelOptionsUIFigure);
            app.AveragineModelParametersPanel.Title = 'Averagine Model Parameters';
            app.AddInfo = uipanel(app.AveragineModelParametersPanel);
            app.AddInfo.Title = 'Create and save new Averagine model';
            app.SaveOutputAsButton = uibutton(app.AddInfo, 'push');
            app.SaveOutputAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputAsButtonPushed, true);
            app.SaveOutputAsButton.Text = 'Save Output As';
            app.OutputFileLocationTextAreaLabel = uilabel(app.AddInfo);
            app.OutputFileLocationTextAreaLabel.Text = 'Output File Location:';
            app.OutputFileLocationTextArea = uitextarea(app.AddInfo);
            app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel = uipanel(app.AddInfo);
            app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel.Title = 'Ratio of elements in composition, max mass limit, and mass resolution:';
            app.CLabel = uilabel(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel);
            app.CLabel.Text = 'C:';
            app.C = uieditfield(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel, 'numeric');
            app.C.Limits = [0 Inf];
            app.C.Value = 0.041142580124245;
            app.HLabel = uilabel(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel);
            app.HLabel.Text = 'H:';
            app.H = uieditfield(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel, 'numeric');
            app.H.Limits = [0 Inf];
            app.H.Value = 0.0667414095012345;
            app.NLabel = uilabel(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel);
            app.NLabel.Text = 'N:';
            app.N = uieditfield(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel, 'numeric');
            app.N.Limits = [0 Inf];
            app.N.Value = 0.0076099396507779;
            app.OLabel = uilabel(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel);
            app.OLabel.Text = 'O:';
            app.O = uieditfield(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel, 'numeric');
            app.O.Limits = [0 Inf];
            app.O.Value = 0.0203467465280654;
            app.SLabel = uilabel(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel);
            app.SLabel.Text = 'S:';
            app.S = uieditfield(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel, 'numeric');
            app.S.Limits = [0 Inf];
            app.S.Value = 0.000219515203030292;
            app.MassLimitDaLabel = uilabel(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel);
            app.MassLimitDaLabel.Text = 'Mass Limit (Da):';
            app.MassLimit = uieditfield(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel, 'numeric');
            app.MassLimit.Limits = [0 Inf];
            app.MassLimit.Value = 10000;
            app.ResolutionDaEditFieldLabel = uilabel(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel);
            app.ResolutionDaEditFieldLabel.Text = 'Resolution (Da):';
            app.Res = uieditfield(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel, 'numeric');
            app.Res.Limits = [0 Inf];
            app.Res.Value = 1;
            app.MassIntervalDaDropDownLabel = uilabel(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel);
            app.MassIntervalDaDropDownLabel.Text = 'Mass Interval (Da):';
            app.MassInt = uidropdown(app.RatioofelementsincompositionmaxmasslimitandmassresolutionPanel);
            app.MassInt.Items = {'1', '2', '3', '5', '10', '20'};
            app.MassInt.Value = '1';
            app.OKButton = uibutton(app.AveragineModelParametersPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.LoadcustomAveraginemodelPanel = uipanel(app.AveragineModelParametersPanel);
            app.LoadcustomAveraginemodelPanel.Title = 'Load custom Averagine model';
            app.SelectDataFileButton = uibutton(app.LoadcustomAveraginemodelPanel, 'push');
            app.SelectDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFileButtonPushed, true);
            app.SelectDataFileButton.Text = 'Select Data File';
            app.DataFileLocationTextAreaLabel = uilabel(app.LoadcustomAveraginemodelPanel);
            app.DataFileLocationTextAreaLabel.Text = 'Data File Location:';
            app.DataFileLocationTextArea = uitextarea(app.LoadcustomAveraginemodelPanel);
            % Show the figure after all components are created
            app.ModelOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modeloptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModelOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModelOptionsUIFigure)
        end
    end
end