classdef preprocessgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreProcessingUIFigure           matlab.ui.Figure
        PreProcessingPanel              matlab.ui.container.Panel
        TriggerOptionsButtonGroup       matlab.ui.container.ButtonGroup
        HCDtriggerlinkButton            matlab.ui.control.Button
        KeeponlyHCDtriggeredspectraButton  matlab.ui.control.RadioButton
        KeepallspectraButton            matlab.ui.control.RadioButton
        QuantitativeAnalysisButtonGroup  matlab.ui.container.ButtonGroup
        IflabelfreequantitationselectedLabel  matlab.ui.control.Label
        MergeMSnSpectra                 matlab.ui.control.CheckBox
        LabelFreeQuantitation           matlab.ui.control.CheckBox
        PrecursorMassOptionsButtonGroup  matlab.ui.container.ButtonGroup
        CreateorloadmodelButton         matlab.ui.control.Button
        CustomAveragine                 matlab.ui.control.RadioButton
        DefaultAveragine                matlab.ui.control.RadioButton
        Preassigned                     matlab.ui.control.RadioButton
        RUNButton                       matlab.ui.control.Button
        SaveOptionsPanel                matlab.ui.container.Panel
        matSaveOptionsButton            matlab.ui.control.Button
        OutputFileLocationTextArea      matlab.ui.control.TextArea
        OutputFileLocationLabel         matlab.ui.control.Label
        SelectOutputmatFileLocationButton  matlab.ui.control.Button
        SelectFiletoReadPanel           matlab.ui.container.Panel
        ppmforallLabel                  matlab.ui.control.EditField
        MS2MassToleranceEditFieldLabel  matlab.ui.control.Label
        ChangeSettingsButton            matlab.ui.control.Button
        MS1MassTol                      matlab.ui.control.NumericEditField
        MS1MassToleranceLabel           matlab.ui.control.Label
        MS1Units                        matlab.ui.control.DropDown
        ScanNumbers                     matlab.ui.control.TextArea
        ScanNumbersTextAreaLabel        matlab.ui.control.Label
        SELECTALL                       matlab.ui.control.CheckBox
        Enterscannumberstoanalyze       matlab.ui.control.Label
        DataFileLocationTextArea        matlab.ui.control.TextArea
        DataFileLocationTextAreaLabel   matlab.ui.control.Label
        AcceptsLabel                    matlab.ui.control.Label
        SelectDataFileButton            matlab.ui.control.Button
        Instructions                    matlab.ui.control.Label
    end

    properties (Access = private)
        % Default values;
        % Information on these values will be explained in the function
        %     where they are used
        DialogApp
        add_cell = [0,0,0,0,0,0,0,0,0,0,0,0,1,1,0,0,1,0];
        trigger_options = {0,1,0,0,0};
        analysis_options = {1,1,1,1,1};
        model_options = [0.0411425801242450,0.0667414095012345,...
            0.00760993965077790,0.0203467465280654,...
            0.000219515203030292,10000,1,1];
        monoisolocation = '';
        monoisoutput = '';
        frag_options = [{35,'ppm';35,'ppm';35,'ppm';35,'ppm';35,'ppm'}];
    end

    methods (Access = public)
        function matoptions(app,add_cell_value)
            app.add_cell = add_cell_value;
            app.matSaveOptionsButton.Enable = 'on';
        end
        function cancelmat(app)
            app.matSaveOptionsButton.Enable = 'on';
        end
        function averagineoptions(app, model_values, dist_location, dist_output)
            app.model_options = model_values;
            app.monoisolocation = dist_location;
            app.monoisoutput = dist_output;
            app.CreateorloadmodelButton.Enable = 'on';
        end
        function cancelmodel(app)
            app.CreateorloadmodelButton.Enable = 'on';
        end
        function parameteroptions(app, trigger_options,analysis_options)
            app.trigger_options = trigger_options;
            app.analysis_options = analysis_options;
            app.HCDtriggerlinkButton.Enable = 'on';
        end
        function canceltrigger(app)
            app.HCDtriggerlinkButton.Enable = 'on';
        end
        function fragoptions(app, frag_values)
            app.frag_options = frag_values;
            app.ChangeSettingsButton.Enable = 'on';
        end
        function cancelfrag(app)
            app.ChangeSettingsButton.Enable = 'on';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initializing all components - set default values
            matoptions(app,app.add_cell);
            parameteroptions(app,app.trigger_options,app.analysis_options);
            averagineoptions(app,app.model_options,app.monoisolocation,app.monoisoutput);
            fragoptions(app,app.frag_options);
            app.SELECTALL.Value = 1;
            app.LabelFreeQuantitation.Value = 1;
            app.MergeMSnSpectra.Value = 1;
        end

        % Button pushed function: SelectDataFileButton
        function SelectDataFileButtonPushed(app, event)
            % Executes when Section "1. Select File to Read and Set
            %     Parameters" - Button "Select Data File" is pushed
            [filename,pathname] = uigetfile({'*.mzML;*.mzXML;*.RAW;*.mgf;*.mat'},'Select a File','MultiSelect','on');
            app.PreProcessingUIFigure.Visible = 'off';
            app.PreProcessingUIFigure.Visible = 'on';
            if(isequal(filename,0) || isequal(pathname,0))
                msgbox('File NOT Selected');
            else
                msfilename = fullfile(pathname,filename);
                app.DataFileLocationTextArea.Value = msfilename;
            end
        end

        % Value changed function: ScanNumbers
        function ScanNumbersValueChanged(app, event)
            % Executes when inputs are made in Section "1. Select File to Read and Set
            %     Parameters" - Edit box "Enter scan numbers to analyze ..."
            app.SELECTALL.Value = 0;  % Uncheck "Use ALl Scan Numbers"
        end

        % Value changed function: SELECTALL
        function SELECTALLValueChanged(app, event)
            % Executes when in Section "1. Select File to Read and Set
            %     Parameters" - Checkbox "Use All Scan Numbers" is checked:
            %     restore default display
            value = app.SELECTALL.Value;
            if value == 1
                app.ScanNumbers.Value = 'Enter desired scans to analyze here.';
            end
        end

        % Button pushed function: ChangeSettingsButton
        function ChangeSettingsButtonPushed(app, event)
            % Executes when Section "1. Select File to Read and Set
            %     Parameters" - Button "Change Settings" is pushed
            app.ChangeSettingsButton.Enable = 'off';
            app.DialogApp = fragtolerances(app,app.frag_options);  % Calls a new GUI
            frag_options_text = app.frag_options;  % Retrieve results from that GUI
            % Compile the results and display in Editbox "MS2 Mass Tolerance:"
            frag_options_text(:,1) = cellfun(@(x) num2str(x),frag_options_text(:,1),'UniformOutput',0);
            U = strjoin(['CID:',frag_options_text(1,1),frag_options_text(1,2),';HCD:',frag_options_text(2,1),frag_options_text(2,2),...
                ';ETC:',frag_options_text(3,1),frag_options_text(3,2),';ETciD:',frag_options_text(4,1),frag_options_text(4,2),...
                ';EThcD:',frag_options_text(5,1),frag_options_text(5,2)],'');
            app.ppmforallLabel.Value = U;
        end

        % Button pushed function: HCDtriggerlinkButton
        function HCDtriggerlinkButtonPushed(app, event)
            % Executes when Section "2. Trigger Options" -
            %     Button "HCD trigger link..." is pushed
            app.HCDtriggerlinkButton.Enable = 'off';
            app.DialogApp = triggeroptions(app,app.trigger_options,app.analysis_options);  % Launch a new GUI
        end

        % Button pushed function: CreateorloadmodelButton
        function CreateorloadmodelButtonPushed(app, event)
            % Executes when Section "3. Precursor Mass Options" -
            %     Button "Create or load model..." is pushed
            app.CreateorloadmodelButton.Enable = 'off';
            app.DialogApp = modeloptions(app,app.model_options,app.monoisolocation,app.monoisoutput);  % Launch a new GUI
        end

        % Value changed function: LabelFreeQuantitation
        function LabelFreeQuantitationValueChanged(app, event)
            % Executes when Section "4. Quantitative Analysis" -
            %     Checkbox "Enable label free quantitation" is
            %     checked/unchecked
            value = app.LabelFreeQuantitation.Value;
            if value == 0
                % Unchecked
                app.MergeMSnSpectra.Enable = 'off';
                app.MergeMSnSpectra.Value = 0;
                app.ChangeSettingsButton.Enable = 'off';
                app.ppmforallLabel.Value = "Values Not Needed";
            else
                % Checked
                app.MergeMSnSpectra.Enable = 'on';
                if app.MergeMSnSpectra.Value == 1  % Checkbox "Merge MSn spectra with..." is checked
                    app.ChangeSettingsButton.Enable = 'on';
                    frag_options_text = app.frag_options;
                    % Load MS2 tolerence info set by user in the GUI
                    %     launched by pressing the button "Change Settings"
                    % Display these information
                    frag_options_text(:,1) = cellfun(@(x) num2str(x),frag_options_text(:,1),'UniformOutput',0);
                    U = strjoin(['CID:',frag_options_text(1,1),frag_options_text(1,2),';HCD:',frag_options_text(2,1),frag_options_text(2,2),...
                        ';ETC:',frag_options_text(3,1),frag_options_text(3,2),';ETciD:',frag_options_text(4,1),frag_options_text(4,2),...
                        ';EThcD:',frag_options_text(5,1),frag_options_text(5,2)],'');
                    app.ppmforallLabel.Value = U;
                end
            end
        end

        % Value changed function: MergeMSnSpectra
        function MergeMSnSpectraValueChanged(app, event)
            % Executes when Section "4. Quantitative Analysis" -
            %     Checkbox "Merge MSn spectra with..." is
            %     checked/unchecked
            value = app.MergeMSnSpectra.Value;
            if value == 0
                % Unchecked
                app.ChangeSettingsButton.Enable = 'off';
                app.ppmforallLabel.Value = "Values Not Needed";
            else
                % Checked
                app.ChangeSettingsButton.Enable = 'on';
                frag_options_text = app.frag_options;
                % Load MS2 tolerence info set by user in the GUI
                %     launched by pressing the button "Change Settings"
                % Display these information
                frag_options_text(:,1) = cellfun(@(x) num2str(x),frag_options_text(:,1),'UniformOutput',0);
                U = strjoin(['CID:',frag_options_text(1,1),frag_options_text(1,2),';HCD:',frag_options_text(2,1),frag_options_text(2,2),...
                    ';ETC:',frag_options_text(3,1),frag_options_text(3,2),';ETciD:',frag_options_text(4,1),frag_options_text(4,2),...
                    ';EThcD:',frag_options_text(5,1),frag_options_text(5,2)],'');
                app.ppmforallLabel.Value = U;
            end
        end

        % Button pushed function: SelectOutputmatFileLocationButton
        function SelectOutputmatFileLocationButtonPushed(app, event)
            % Executes when Section "5. Save Options" -
            %     Button "Select Output .mat File Location" is pressed
            selpath = uigetdir(path,'Select Output Location');
            app.PreProcessingUIFigure.Visible = 'off';
            app.PreProcessingUIFigure.Visible = 'on';
            if (isequal(selpath,0))
                msgbox('Output Location NOT Selected');
            else
                app.OutputFileLocationTextArea.Value = selpath;
            end
        end

        % Button pushed function: matSaveOptionsButton
        function matSaveOptionsButtonPushed(app, event)
            % Executes when Section "5. Save Options" -
            %     Button ".mat Save Options" is pressed
            app.matSaveOptionsButton.Enable = 'off';
            app.DialogApp = saveoptions(app,app.add_cell);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % Executes when Button "RUN" is pressed
            filename = app.DataFileLocationTextArea.Value;
            if isempty(filename{1})  % No expt. data file chosen
                msgbox('Error: No file selected.');
            else
                selpath = app.OutputFileLocationTextArea.Value;
                if isempty(selpath{1})  % Section "1. Select File..."
                    % Editbox "Data File Location" is empty - No data file
                    % chosen or loading failed
                    msgbox('Error: No output location selected.');
                else
                    tic
                    for i = 1:length(filename)
                        [~,B,C] = fileparts(filename{i});
                        %% Load or create msdata.mat file
                        if strcmpi(C,'.mat')
                            % Input is .mat file
                            f = msgbox('Loading .mat file...');
                            msdata_mat = load(filename{i});
                            try
                                msdata = msdata_mat.msdata;
                            catch
                                msdata = msdata_mat.msdata_QA;
                            end
                            close(f);
                        else  % Other supported formats
                            additionalinfo = {'peaksCount','polarity','scanType','centroided',...
                                'deisotoped','chargeDeconvoluted','startMz','endMz','lowMz','highMz',...
                                'basePeakMz','basePeakIntensity','totIonCurrent','precursorScanNum',...
                                'ionisationEnergy','precision','spectra','filterLine'};
                            idx = [1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,16,17,18];
                            adx = nonzeros(idx.*app.add_cell);
                            add_in = additionalinfo(adx);
                            infos = [{'std'},add_in];
                            % Above: based on pre-defined information items
                            %     and user selections, load these
                            %     information from expt. data file
                            msdata = getmsdata(filename{i},infos,selpath{1});
                            m = msgbox('Saving msdata.mat file...');
                            matfile1 = strcat(selpath,'\',B,'.mat');
                            save(matfile1{1},'msdata','-v7.3');
                            close(m);
                        end
                        % Determine triggered spectra
                        if app.KeeponlyHCDtriggeredspectraButton.Value == 1
                            g = msgbox('Determining triggered spectra...');
                            options.mode = 'PID';
                            analy_values = {'CID','HCD','ETD','ETCID','ETHCD'};
                            triggermode = [app.trigger_options{1},app.trigger_options{2},app.trigger_options{3},...
                                app.trigger_options{4},app.trigger_options{5}];
                            analysismode = [app.analysis_options{1},app.analysis_options{2},app.analysis_options{3},...
                                app.analysis_options{4},app.analysis_options{5}];
                            options.triggermode = analy_values(nonzeros(triggermode.*[1,2,3,4,5]));
                            adk = nonzeros(analysismode.*[1,2,3,4,5]);
                            options.analymode = analy_values(adk);
                            [SASSO,colnames] = buildm2sasso(msdata,options);  % Build association between triggered scan
                            close(g);
                            h = msgbox('Saving trigger.mat file...');
                            if app.LabelFreeQuantitation.Value == 1
                                triggerfile = strcat(selpath,'\',B,'_QA_trigger.mat');
                            else
                                triggerfile = strcat(selpath,'\',B,'_trigger.mat');
                            end
                            save(triggerfile{1},'SASSO','colnames');
                            close(h);
                            keep = SASSO(:,1:(end-2));
                            scannums_trigger = unique(keep);
                            if ismember(0,scannums_trigger)
                                scannums_trigger = scannums_trigger(2:end);
                            end
                        end
                        % Select scan numbers to be analyzed
                        if app.SELECTALL.Value == 1  % Analyze all scans
                            if app.KeeponlyHCDtriggeredspectraButton.Value == 1
                                scannums = scannums_trigger;
                            else
                                scannums = msdata.scannum;
                            end
                        else  % Only analyze user selected scans.
                            scannums_txt = strcat('[',app.ScanNumbers.Value,']');
                            if contains(app.ScanNumbers.Value,'-')
                                temp=split(app.ScanNumbers.Value,'-');
                                startX=str2num(cell2mat(temp(1)));
                                stopX=str2num(cell2mat(temp(2)));
                                scannums_select = [startX:stopX]';
                            else
                                scannums_select = eval(scannums_txt{1})';
                            end
                            if app.KeeponlyHCDtriggeredspectraButton.Value == 1
                                scan_int = ismember(scannums_select,scannums_trigger);
                                scannums = scannums_select(scan_int);
                            else
                                scannums = scannums_select;
                            end
                        end
                        chnos = []; % Container for user custom averagine model. If default model is used it is empty.
                        msdata_QA = msdata;
                        % Perform averagine calculations and Quantitative Analysis
                        if app.LabelFreeQuantitation.Value == 1 || app.Preassigned.Value ~= 1
                            if app.DefaultAveragine.Value == 1
                                default_isodist = load('default_iso_dist.mat');
                                isodist = default_isodist.defaultisodist;
                                % Adjust monoisotopic mass of precursor ions
                                if app.KeeponlyHCDtriggeredspectraButton.Value == 1
                                    msdata_QA = defaultmonoiso(scannums,msdata,isodist,chnos,0,SASSO);
                                else
                                    msdata_QA = defaultmonoiso(scannums,msdata,isodist,chnos,0);
                                end
                            elseif app.CustomAveragine.Value == 1
                                chnos = [app.model_options(1),app.model_options(2),app.model_options(3),...
                                    app.model_options(4),app.model_options(5)];
                                if ~isempty(app.monoisolocation{1})
                                    load_isodist = load(app.monoisolocation{1});
                                    isodist = load_isodist.defaultisodist;
                                else
                                    isodist = customIsoDist(chnos,app.model_options(6),...
                                        app.model_options(8),app.model_options(7));
                                    k = msgbox('Saving isodist.mat file...');
                                    isofile1 = strcat(app.monoisoutput{1},'\','isodist.mat');
                                    save(isofile1,'isodist','-v7.3');
                                    close(k);
                                end
                                if app.KeeponlyHCDtriggeredspectraButton.Value == 1
                                    msdata_QA = defaultmonoiso(scannums,msdata,isodist,chnos,0,SASSO);
                                else
                                    msdata_QA = defaultmonoiso(scannums,msdata,isodist,chnos,0);
                                end
                            end
                            if app.LabelFreeQuantitation.Value == 1
                                msdata_QA = quantitativeAnalysis(scannums,msdata_QA,app.MS1MassTol.Value,app.MS1Units.Value,...
                                    app.frag_options,0.6,0.2,1,chnos,app.LabelFreeQuantitation.Value,...
                                    app.MergeMSnSpectra.Value,0,0);
                            end
                            n = msgbox('Saving QA.mat file...');
                            matfile2 = strcat(selpath,'\',B,'_QA.mat');
                            save(matfile2{1},'msdata_QA','-v7.3');
                            close(n);
                        end
                        % END OF PROGRAM ----------------------------------------------------------------------------------------------
                        elapsed = toc;
                        line1 = ('Data Processing Complete');
                        line2 = strcat('Elapsed time:  ',num2str(elapsed),' seconds');
                        if length(filename) == 1
                            uiwait(msgbox({line1;line2}));
                        else
                            f = msgbox({line1;line2});
                            pause(5);
                            close(f);
                        end
                        % -------------------------------------------------------------------------------------------------------------
                    end
                end
            end
        end

        % Close request function: PreProcessingUIFigure
        function PreProcessingUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreProcessingUIFigure = uifigure('Visible', 'off');
            app.PreProcessingUIFigure.Name = 'Pre Processing';
            app.PreProcessingUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreProcessingUIFigureCloseRequest, true);
            app.PreProcessingPanel = uipanel(app.PreProcessingUIFigure);
            app.PreProcessingPanel.Title = 'Pre-Processing';
            app.Instructions = uilabel(app.PreProcessingPanel);
            app.Instructions.Text = 'Create .mat file from raw MS data for analysis using the 5 step procedure below.';
            app.SelectFiletoReadPanel = uipanel(app.PreProcessingPanel);
            app.SelectFiletoReadPanel.Title = '1. Select File to Read and Set Parameters';
            app.SelectDataFileButton = uibutton(app.SelectFiletoReadPanel, 'push');
            app.SelectDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFileButtonPushed, true);
            app.SelectDataFileButton.Text = 'Select Data File';
            app.AcceptsLabel = uilabel(app.SelectFiletoReadPanel);
            app.AcceptsLabel.Text = 'Accepts .raw, .mzML, .mzXML, and .mat files';
            app.DataFileLocationTextAreaLabel = uilabel(app.SelectFiletoReadPanel);
            app.DataFileLocationTextAreaLabel.Text = 'Data File Location:';
            app.DataFileLocationTextArea = uitextarea(app.SelectFiletoReadPanel);
            app.Enterscannumberstoanalyze = uilabel(app.SelectFiletoReadPanel);
            app.Enterscannumberstoanalyze.Text = 'Enter scan numbers to analyze, (e.g. 1:3,7,9,11:20).';
            app.SELECTALL = uicheckbox(app.SelectFiletoReadPanel);
            app.SELECTALL.ValueChangedFcn = createCallbackFcn(app, @SELECTALLValueChanged, true);
            app.SELECTALL.Text = 'Use All Scan Numbers';
            app.ScanNumbersTextAreaLabel = uilabel(app.SelectFiletoReadPanel);
            app.ScanNumbersTextAreaLabel.Text = 'Scan Numbers:';
            app.ScanNumbers = uitextarea(app.SelectFiletoReadPanel);
            app.ScanNumbers.ValueChangedFcn = createCallbackFcn(app, @ScanNumbersValueChanged, true);
            app.ScanNumbers.Value = {'Enter desired scans to analyze here.'};
            app.MS1Units = uidropdown(app.SelectFiletoReadPanel);
            app.MS1Units.Items = {'ppm', 'Da'};
            app.MS1Units.Value = 'ppm';
            app.MS1MassToleranceLabel = uilabel(app.SelectFiletoReadPanel);
            app.MS1MassToleranceLabel.Text = 'MS1 Mass Tolerance:';
            app.MS1MassTol = uieditfield(app.SelectFiletoReadPanel, 'numeric');
            app.MS1MassTol.Value = 10;
            app.ChangeSettingsButton = uibutton(app.SelectFiletoReadPanel, 'push');
            app.ChangeSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeSettingsButtonPushed, true);
            app.ChangeSettingsButton.Text = 'Change Settings';
            app.MS2MassToleranceEditFieldLabel = uilabel(app.SelectFiletoReadPanel);
            app.MS2MassToleranceEditFieldLabel.Text = 'MS2 Mass Tolerance:';
            app.ppmforallLabel = uieditfield(app.SelectFiletoReadPanel, 'text');
            app.ppmforallLabel.Value = 'Default Values';
            app.SaveOptionsPanel = uipanel(app.PreProcessingPanel);
            app.SaveOptionsPanel.Title = '5. Save Options';
            app.SelectOutputmatFileLocationButton = uibutton(app.SaveOptionsPanel, 'push');
            app.SelectOutputmatFileLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputmatFileLocationButtonPushed, true);
            app.SelectOutputmatFileLocationButton.Text = 'Select Output .mat File Location';
            app.OutputFileLocationLabel = uilabel(app.SaveOptionsPanel);
            app.OutputFileLocationLabel.Text = 'Output File Location:';
            app.OutputFileLocationTextArea = uitextarea(app.SaveOptionsPanel);
            app.matSaveOptionsButton = uibutton(app.SaveOptionsPanel, 'push');
            app.matSaveOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @matSaveOptionsButtonPushed, true);
            app.matSaveOptionsButton.Text = '.mat Save Options...';
            app.RUNButton = uibutton(app.PreProcessingPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.PrecursorMassOptionsButtonGroup = uibuttongroup(app.PreProcessingPanel);
            app.PrecursorMassOptionsButtonGroup.Title = '3. Precursor Mass Options';
            app.Preassigned = uiradiobutton(app.PrecursorMassOptionsButtonGroup);
            app.Preassigned.Text = 'Use preassigned instrument values';
            app.DefaultAveragine = uiradiobutton(app.PrecursorMassOptionsButtonGroup);
            app.DefaultAveragine.Text = 'Use default Averagine model';
            app.DefaultAveragine.Value = true;
            app.CustomAveragine = uiradiobutton(app.PrecursorMassOptionsButtonGroup);
            app.CustomAveragine.Text = 'Use custom Averagine model';
            app.CreateorloadmodelButton = uibutton(app.PrecursorMassOptionsButtonGroup, 'push');
            app.CreateorloadmodelButton.ButtonPushedFcn = createCallbackFcn(app, @CreateorloadmodelButtonPushed, true);
            app.CreateorloadmodelButton.Text = 'Create or load model...';
            app.QuantitativeAnalysisButtonGroup = uibuttongroup(app.PreProcessingPanel);
            app.QuantitativeAnalysisButtonGroup.Title = '4. Quantitative Analysis';
            app.LabelFreeQuantitation = uicheckbox(app.QuantitativeAnalysisButtonGroup);
            app.LabelFreeQuantitation.ValueChangedFcn = createCallbackFcn(app, @LabelFreeQuantitationValueChanged, true);
            app.LabelFreeQuantitation.Text = 'Enable label free quantitation';
            app.LabelFreeQuantitation.Value = true;
            app.MergeMSnSpectra = uicheckbox(app.QuantitativeAnalysisButtonGroup);
            app.MergeMSnSpectra.ValueChangedFcn = createCallbackFcn(app, @MergeMSnSpectraValueChanged, true);
            app.MergeMSnSpectra.Text = {'  Merge MSn spectra with'; '  same precursor mass,'; '  retention time,  and '; '  fragmentation pattern'};
            app.IflabelfreequantitationselectedLabel = uilabel(app.QuantitativeAnalysisButtonGroup);
            app.IflabelfreequantitationselectedLabel.Text = 'If label free quantitation selected:';
            app.TriggerOptionsButtonGroup = uibuttongroup(app.PreProcessingPanel);
            app.TriggerOptionsButtonGroup.Title = '2. Trigger Options';
            app.KeepallspectraButton = uiradiobutton(app.TriggerOptionsButtonGroup);
            app.KeepallspectraButton.Text = 'Keep all spectra';
            app.KeeponlyHCDtriggeredspectraButton = uiradiobutton(app.TriggerOptionsButtonGroup);
            app.KeeponlyHCDtriggeredspectraButton.Text = 'Keep only HCD triggered spectra';
            app.KeeponlyHCDtriggeredspectraButton.Value = true;
            app.HCDtriggerlinkButton = uibutton(app.TriggerOptionsButtonGroup, 'push');
            app.HCDtriggerlinkButton.ButtonPushedFcn = createCallbackFcn(app, @HCDtriggerlinkButtonPushed, true);
            app.HCDtriggerlinkButton.Text = 'HCD trigger link...';
            % Show the figure after all components are created
            app.PreProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preprocessgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PreProcessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreProcessingUIFigure)
        end
    end
end