classdef saveoptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SaveOptionsUIFigure       matlab.ui.Figure
        matSaveOptionsPanel       matlab.ui.container.Panel
        AddInfo                   matlab.ui.container.Panel
        PeaksCount                matlab.ui.control.CheckBox
        Polarity                  matlab.ui.control.CheckBox
        ScanType                  matlab.ui.control.CheckBox
        Centroid                  matlab.ui.control.CheckBox
        Deisotope                 matlab.ui.control.CheckBox
        Deconv                    matlab.ui.control.CheckBox
        StartMZ                   matlab.ui.control.CheckBox
        EndMZ                     matlab.ui.control.CheckBox
        LowMZ                     matlab.ui.control.CheckBox
        HighMZ                    matlab.ui.control.CheckBox
        BaseMZ                    matlab.ui.control.CheckBox
        BaseInt                   matlab.ui.control.CheckBox
        SelectAllADD              matlab.ui.control.CheckBox
        TIC                       matlab.ui.control.CheckBox
        PreScanNum                matlab.ui.control.CheckBox
        Energy                    matlab.ui.control.CheckBox
        Precision                 matlab.ui.control.CheckBox
        Spectra                   matlab.ui.control.CheckBox
        FilterLine                matlab.ui.control.CheckBox
        OKButton                  matlab.ui.control.Button
        PleaseselectwhichpropertiesyouwantsavedLabel  matlab.ui.control.Label
        StandardInformationPanel  matlab.ui.container.Panel
        IncludesLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp1
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, preprocessgui, add_cell)
            app.CallingApp1 = preprocessgui;
            app.PeaksCount.Value = add_cell(1);
            app.Polarity.Value = add_cell(2);
            app.ScanType.Value = add_cell(3);
            app.Centroid.Value = add_cell(4);
            app.Deisotope.Value = add_cell(5);
            app.Deconv.Value = add_cell(6);
            app.StartMZ.Value = add_cell(7);
            app.EndMZ.Value = add_cell(8);
            app.LowMZ.Value = add_cell(9);
            app.HighMZ.Value = add_cell(10);
            app.BaseMZ.Value = add_cell(11);
            app.BaseInt.Value = add_cell(12);
            app.TIC.Value = add_cell(13);
            app.PreScanNum.Value = add_cell(14);
            app.Energy.Value = add_cell(15);
            app.Precision.Value = add_cell(16);
            app.Spectra.Value = add_cell(17);
            app.FilterLine.Value = add_cell(18);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Compile user selections then pass to parent GUI
            add_cell = [app.PeaksCount.Value,app.Polarity.Value,app.ScanType.Value,...
                app.Centroid.Value,app.Deisotope.Value,app.Deconv.Value,app.StartMZ.Value,...
                app.EndMZ.Value,app.LowMZ.Value,app.HighMZ.Value,app.BaseMZ.Value,...
                app.BaseInt.Value,app.TIC.Value,app.PreScanNum.Value,app.Energy.Value,...
                app.Precision.Value,app.Spectra.Value,app.FilterLine.Value];
            matoptions(app.CallingApp1,add_cell);
            delete(app)
        end

        % Value changed function: SelectAllADD
        function SelectAllADDValueChanged(app, event)
            value = app.SelectAllADD.Value;
            if value == 1
                app.PeaksCount.Value = 1;
                app.Polarity.Value = 1;
                app.ScanType.Value = 1;
                app.Centroid.Value = 1;
                app.Deisotope.Value = 1;
                app.Deconv.Value = 1;
                app.StartMZ.Value = 1;
                app.EndMZ.Value = 1;
                app.LowMZ.Value = 1;
                app.HighMZ.Value = 1;
                app.BaseMZ.Value = 1;
                app.BaseInt.Value = 1;
                app.TIC.Value = 1;
                app.PreScanNum.Value = 1;
                app.Energy.Value = 1;
                app.Precision.Value = 1;
                app.Spectra.Value = 1;
                app.FilterLine.Value = 1;
            else
                app.PeaksCount.Value = 0;
                app.Polarity.Value = 0;
                app.ScanType.Value = 0;
                app.Centroid.Value = 0;
                app.Deisotope.Value = 0;
                app.Deconv.Value = 0;
                app.StartMZ.Value = 0;
                app.EndMZ.Value = 0;
                app.LowMZ.Value = 0;
                app.HighMZ.Value = 0;
                app.BaseMZ.Value = 0;
                app.BaseInt.Value = 0;
                app.TIC.Value = 0;
                app.PreScanNum.Value = 0;
                app.Energy.Value = 0;
                app.Precision.Value = 0;
                app.Spectra.Value = 0;
                app.FilterLine.Value = 0;
            end
        end

        % Close request function: SaveOptionsUIFigure
        function SaveOptionsUIFigureCloseRequest(app, event)
            cancelmat(app.CallingApp1);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SaveOptionsUIFigure = uifigure('Visible', 'off');
            app.SaveOptionsUIFigure.Name = 'Save Options';
            app.SaveOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @SaveOptionsUIFigureCloseRequest, true);
            app.matSaveOptionsPanel = uipanel(app.SaveOptionsUIFigure);
            app.matSaveOptionsPanel.Title = '.mat Save Options';
            app.AddInfo = uipanel(app.matSaveOptionsPanel);
            app.AddInfo.Title = 'Additional Information';
            app.PeaksCount = uicheckbox(app.AddInfo);
            app.PeaksCount.Text = 'Peaks Counted';
            app.Polarity = uicheckbox(app.AddInfo);
            app.Polarity.Text = 'Polarity';
            app.ScanType = uicheckbox(app.AddInfo);
            app.ScanType.Text = 'Scan Type';
            app.Centroid = uicheckbox(app.AddInfo);
            app.Centroid.Text = 'Centroided';
            app.Deisotope = uicheckbox(app.AddInfo);
            app.Deisotope.Text = 'Deisotoped';
            app.Deconv = uicheckbox(app.AddInfo);
            app.Deconv.Text = 'Charge Deconvoluted';
            app.StartMZ = uicheckbox(app.AddInfo);
            app.StartMZ.Text = 'Start m/z';
            app.EndMZ = uicheckbox(app.AddInfo);
            app.EndMZ.Text = 'End m/z';
            app.LowMZ = uicheckbox(app.AddInfo);
            app.LowMZ.Text = 'Low m/z';
            app.HighMZ = uicheckbox(app.AddInfo);
            app.HighMZ.Text = 'High m/z';
            app.BaseMZ = uicheckbox(app.AddInfo);
            app.BaseMZ.Text = 'Base Peak m/z';
            app.BaseInt = uicheckbox(app.AddInfo);
            app.BaseInt.Text = 'Base Peak Intensity';
            app.SelectAllADD = uicheckbox(app.AddInfo);
            app.SelectAllADD.ValueChangedFcn = createCallbackFcn(app, @SelectAllADDValueChanged, true);
            app.SelectAllADD.Text = 'Select All';
            app.TIC = uicheckbox(app.AddInfo);
            app.TIC.Text = 'Total Ion Current';
            app.PreScanNum = uicheckbox(app.AddInfo);
            app.PreScanNum.Text = 'Precursor Scan Num';
            app.Energy = uicheckbox(app.AddInfo);
            app.Energy.Text = 'Ionization Energy';
            app.Precision = uicheckbox(app.AddInfo);
            app.Precision.Text = 'Precision';
            app.Spectra = uicheckbox(app.AddInfo);
            app.Spectra.Text = 'Spectra';
            app.FilterLine = uicheckbox(app.AddInfo);
            app.FilterLine.Text = 'Filter Line';
            app.OKButton = uibutton(app.matSaveOptionsPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.PleaseselectwhichpropertiesyouwantsavedLabel = uilabel(app.matSaveOptionsPanel);
            app.PleaseselectwhichpropertiesyouwantsavedLabel.Text = 'Please select which properties you want saved.';
            app.StandardInformationPanel = uipanel(app.matSaveOptionsPanel);
            app.StandardInformationPanel.Title = 'Standard Information';
            app.IncludesLabel = uilabel(app.StandardInformationPanel);
            app.IncludesLabel.Text = {'Includes: Scan Number, MS Level, Retention Time, Precursor m/z,'; '                Precursor Charge, and Fragmentation Mode'};
            % Show the figure after all components are created
            app.SaveOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = saveoptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SaveOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SaveOptionsUIFigure)
        end
    end
end