classdef triggeroptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TriggerOptionsUIFigure  matlab.ui.Figure
        HCDTriggeredSpectraOptionParametersPanel  matlab.ui.container.Panel
        OKButton                matlab.ui.control.Button
        SelectmodestolinktriggertriggereddataLabel  matlab.ui.control.Label
        AnalysisModePanel       matlab.ui.container.Panel
        CID_analysis            matlab.ui.control.CheckBox
        ETD_analysis            matlab.ui.control.CheckBox
        ETCID_analysis          matlab.ui.control.CheckBox
        ETHCD_analysis          matlab.ui.control.CheckBox
        HCD_analysis            matlab.ui.control.CheckBox
        TriggerModePanel        matlab.ui.container.Panel
        CID_trigger             matlab.ui.control.CheckBox
        ETD_trigger             matlab.ui.control.CheckBox
        ETCID_trigger           matlab.ui.control.CheckBox
        ETHCD_trigger           matlab.ui.control.CheckBox
        HCD_trigger             matlab.ui.control.CheckBox
    end


    properties (Access = private)
        CallingApp3
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, preprocessgui, trigger_options, analysis_options)
            app.CallingApp3 = preprocessgui;
            app.CID_analysis.Value = analysis_options{1};
            app.HCD_analysis.Value = analysis_options{2};
            app.ETD_analysis.Value = analysis_options{3};
            app.ETCID_analysis.Value = analysis_options{4};
            app.ETHCD_analysis.Value = analysis_options{5};
            app.CID_trigger.Value = trigger_options{1};
            app.HCD_trigger.Value = trigger_options{2};
            app.ETD_trigger.Value = trigger_options{3};
            app.ETCID_trigger.Value = trigger_options{4};
            app.ETHCD_trigger.Value = trigger_options{5};
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Pack use selection then pass them to parent GUI
            trigger_options = {app.CID_trigger.Value,app.HCD_trigger.Value...
                               app.ETD_trigger.Value,app.ETCID_trigger.Value,app.ETHCD_trigger.Value};
            analysis_options = {app.CID_analysis.Value,app.HCD_analysis.Value...
                               app.ETD_analysis.Value,app.ETCID_analysis.Value,app.ETHCD_analysis.Value};
            parameteroptions(app.CallingApp3,trigger_options,analysis_options);
            delete(app)
        end

        % Close request function: TriggerOptionsUIFigure
        function TriggerOptionsUIFigureCloseRequest(app, event)
            canceltrigger(app.CallingApp3);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TriggerOptionsUIFigure = uifigure('Visible', 'off');
            app.TriggerOptionsUIFigure.Name = 'Trigger Options';
            app.TriggerOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @TriggerOptionsUIFigureCloseRequest, true);
            app.HCDTriggeredSpectraOptionParametersPanel = uipanel(app.TriggerOptionsUIFigure);
            app.HCDTriggeredSpectraOptionParametersPanel.Title = 'HCD Triggered Spectra Option Parameters';
            app.OKButton = uibutton(app.HCDTriggeredSpectraOptionParametersPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SelectmodestolinktriggertriggereddataLabel = uilabel(app.HCDTriggeredSpectraOptionParametersPanel);
            app.SelectmodestolinktriggertriggereddataLabel.Text = 'Select modes to link trigger-triggered data.';
            app.AnalysisModePanel = uipanel(app.HCDTriggeredSpectraOptionParametersPanel);
            app.AnalysisModePanel.Title = 'Analysis Mode';
            app.CID_analysis = uicheckbox(app.AnalysisModePanel);
            app.CID_analysis.Text = 'CID';
            app.ETD_analysis = uicheckbox(app.AnalysisModePanel);
            app.ETD_analysis.Text = 'ETD';
            app.ETCID_analysis = uicheckbox(app.AnalysisModePanel);
            app.ETCID_analysis.Text = 'ETciD';
            app.ETHCD_analysis = uicheckbox(app.AnalysisModePanel);
            app.ETHCD_analysis.Text = 'EThcD';
            app.HCD_analysis = uicheckbox(app.AnalysisModePanel);
            app.HCD_analysis.Text = 'HCD';
            app.TriggerModePanel = uipanel(app.HCDTriggeredSpectraOptionParametersPanel);
            app.TriggerModePanel.Title = 'Trigger Mode';
            app.CID_trigger = uicheckbox(app.TriggerModePanel);
            app.CID_trigger.Text = 'CID';
            app.ETD_trigger = uicheckbox(app.TriggerModePanel);
            app.ETD_trigger.Text = 'ETD';
            app.ETCID_trigger = uicheckbox(app.TriggerModePanel);
            app.ETCID_trigger.Text = 'ETciD';
            app.ETHCD_trigger = uicheckbox(app.TriggerModePanel);
            app.ETHCD_trigger.Text = 'EThcD';
            app.HCD_trigger = uicheckbox(app.TriggerModePanel);
            app.HCD_trigger.Text = 'HCD';
            % Show the figure after all components are created
            app.TriggerOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = triggeroptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TriggerOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TriggerOptionsUIFigure)
        end
    end
end