classdef xmlreader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StdInfo                     matlab.ui.container.Panel
        ScanNum                     matlab.ui.control.CheckBox
        MSLevel                     matlab.ui.control.CheckBox
        RetTime                     matlab.ui.control.CheckBox
        PreMZ                       matlab.ui.control.CheckBox
        PreZ                        matlab.ui.control.CheckBox
        FragMode                    matlab.ui.control.CheckBox
        SelectAllSTD                matlab.ui.control.CheckBox
        AddInfo                     matlab.ui.container.Panel
        PeaksCount                  matlab.ui.control.CheckBox
        Polarity                    matlab.ui.control.CheckBox
        ScanType                    matlab.ui.control.CheckBox
        Centroid                    matlab.ui.control.CheckBox
        Deisotope                   matlab.ui.control.CheckBox
        Deconv                      matlab.ui.control.CheckBox
        StartMZ                     matlab.ui.control.CheckBox
        EndMZ                       matlab.ui.control.CheckBox
        LowMZ                       matlab.ui.control.CheckBox
        HighMZ                      matlab.ui.control.CheckBox
        BaseMZ                      matlab.ui.control.CheckBox
        BaseInt                     matlab.ui.control.CheckBox
        SelectAllADD                matlab.ui.control.CheckBox
        TIC                         matlab.ui.control.CheckBox
        PreScanNum                  matlab.ui.control.CheckBox
        Energy                      matlab.ui.control.CheckBox
        Precision                   matlab.ui.control.CheckBox
        Spectra                     matlab.ui.control.CheckBox
        FilterLine                  matlab.ui.control.CheckBox
        SelectXMLFileButton         matlab.ui.control.Button
        XMLFileLocationLabel        matlab.ui.control.Label
        XMLFileLocationTextArea     matlab.ui.control.TextArea
        OutputFileLocationTextAreaLabel  matlab.ui.control.Label
        OutputFileLocationTextArea  matlab.ui.control.TextArea
        SelectOutputButton          matlab.ui.control.Button
        XMLFileReaderLabel          matlab.ui.control.Label
        READButton                  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: READButton
        function READButtonPushed(app, event)
            msfilename = app.XMLFileLocationTextArea.Value;
            if isempty(msfilename{1})
                msgbox('Error: No XML file selected.');
            else
                selpath = app.OutputFileLocationTextArea.Value;
                if isempty(selpath{1})
                    msgbox('Error: No output location selected.');
                else
                    matfile = fullfile(selpath,'msdata.mat');
                    A = exist(matfile{1});
                    if (A == 0)
                        if app.SelectAllSTD.Value == 1
                            std_cell = {'std'}
                        else
                            standardinfo = {'num','msLevel','retentionTime',...
                                'precursorMz','precursorCharge','fragmode'};
                            std = [app.ScanNum.Value,app.MSLevel.Value,app.RetTime.Value,...
                                app.PreMZ.Value,app.PreZ.Value,app.FragMode.Value];
                            std_cell = standardinfo(std);
                        end
                        additionalinfo = {'peaksCount','polarity','scanType','centroided',...
                            'deisotoped','chargeDeconvoluted','startMz','endMz','lowMz','highMz',...
                            'basePeakMz','basePeakIntensity','totIonCurrent','precursorScanNum',...
                            'ionisationEnergy','precision','spectra','filterLine'};
                        add = [app.PeaksCount.Value,app.Polarity.Value,app.ScanType.Value,...
                            app.Centroid.Value,app.Deisotope.Value,app.Deconv.Value,app.StartMZ.Value,...
                            app.EndMZ.Value,app.LowMZ.Value,app.HighMZ.Value,app.BaseMZ.Value,...
                            app.BaseInt.Value,app.TIC.Value,app.PreScanNum.Value,app.Energy.Value,...
                            app.Precision.Value,app.Spectra.Value,app.FilterLine.Value];
                        add_cell = additionalinfo(add);
                        infos_cell = [std_cell,add_cell];
                        infos = strjoin(infos_cell,',');
                        msdata = getmsdata(msfilename{1},{infos});
                        save(matfile{1},'msdata');
                    else
                        msgbox('Error: File msdata.mat already exists in output file location.');
                    end
                end
            end
        end

        % Value changed function: SelectAllADD
        function SelectAllAdd(app, event)
            value = app.SelectAllADD.Value;
            if value == 1
                app.PeaksCount.Value = 1;
                app.Polarity.Value = 1;
                app.ScanType.Value = 1;
                app.Centroid.Value = 1;
                app.Deisotope.Value = 1;
                app.Deconv.Value = 1;
                app.StartMZ.Value = 1;
                app.EndMZ.Value = 1;
                app.LowMZ.Value = 1;
                app.HighMZ.Value = 1;
                app.BaseMZ.Value = 1;
                app.BaseInt.Value = 1;
                app.TIC.Value = 1;
                app.PreScanNum.Value = 1;
                app.Energy.Value = 1;
                app.Precision.Value = 1;
                app.Spectra.Value = 1;
                app.FilterLine.Value = 1;
            else
                app.PeaksCount.Value = 0;
                app.Polarity.Value = 0;
                app.ScanType.Value = 0;
                app.Centroid.Value = 0;
                app.Deisotope.Value = 0;
                app.Deconv.Value = 0;
                app.StartMZ.Value = 0;
                app.EndMZ.Value = 0;
                app.LowMZ.Value = 0;
                app.HighMZ.Value = 0;
                app.BaseMZ.Value = 0;
                app.BaseInt.Value = 0;
                app.TIC.Value = 0;
                app.PreScanNum.Value = 0;
                app.Energy.Value = 0;
                app.Precision.Value = 0;
                app.Spectra.Value = 0;
                app.FilterLine.Value = 0;
            end
        end

        % Value changed function: SelectAllSTD
        function SelectAllStd(app, event)
            value = app.SelectAllSTD.Value;
            if value == 1
                app.ScanNum.Value = 1;
                app.MSLevel.Value = 1;
                app.RetTime.Value = 1;
                app.PreMZ.Value = 1;
                app.PreZ.Value = 1;
                app.FragMode.Value = 1;
            else
                app.ScanNum.Value = 0;
                app.MSLevel.Value = 0;
                app.RetTime.Value = 0;
                app.PreMZ.Value = 0;
                app.PreZ.Value = 0;
                app.FragMode.Value = 0;
            end
        end

        % Callback function
        function displayFile(app, event)
            value = app.XMLFileLocationTextArea.Value;
        end

        % Button pushed function: SelectXMLFileButton
        function getXMLfile(app, event)
            [filename,pathname] = uigetfile({'*.mzML;*.mzXML;*.RAW;*.mgf'},'Select a File');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if(isequal(filename,0) || isequal(pathname,0))
                msgbox('File NOT Selected');
            else
                msfilename = fullfile(pathname,filename);
                app.XMLFileLocationTextArea.Value = msfilename;
            end
        end

        % Button pushed function: SelectOutputButton
        function selectOutput(app, event)
            selpath = uigetdir(path,'Select Output Location');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if (isequal(selpath,0))
                msgbox('Output Location NOT Selected');
            else
                app.OutputFileLocationTextArea.Value = selpath;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StdInfo = uipanel(app.UIFigure);
            app.StdInfo.Title = 'Standard Information';
            app.ScanNum = uicheckbox(app.StdInfo);
            app.ScanNum.Text = 'Scan Number';
            app.MSLevel = uicheckbox(app.StdInfo);
            app.MSLevel.Text = 'MS Level';
            app.RetTime = uicheckbox(app.StdInfo);
            app.RetTime.Text = 'Retention Time';
            app.PreMZ = uicheckbox(app.StdInfo);
            app.PreMZ.Text = 'Precursor m/z';
            app.PreZ = uicheckbox(app.StdInfo);
            app.PreZ.Text = 'Precursor z';
            app.FragMode = uicheckbox(app.StdInfo);
            app.FragMode.Text = 'Fragmentation Mode';
            app.SelectAllSTD = uicheckbox(app.StdInfo);
            app.SelectAllSTD.ValueChangedFcn = createCallbackFcn(app, @SelectAllStd, true);
            app.SelectAllSTD.Text = 'Select All';
            app.AddInfo = uipanel(app.UIFigure);
            app.AddInfo.Title = 'Additional Information';
            app.PeaksCount = uicheckbox(app.AddInfo);
            app.PeaksCount.Text = 'Peaks Counted';
            app.Polarity = uicheckbox(app.AddInfo);
            app.Polarity.Text = 'Polarity';
            app.ScanType = uicheckbox(app.AddInfo);
            app.ScanType.Text = 'Scan Type';
            app.Centroid = uicheckbox(app.AddInfo);
            app.Centroid.Text = 'Centroided';
            app.Deisotope = uicheckbox(app.AddInfo);
            app.Deisotope.Text = 'Deisotoped';
            app.Deconv = uicheckbox(app.AddInfo);
            app.Deconv.Text = 'Charge Deconvoluted';
            app.StartMZ = uicheckbox(app.AddInfo);
            app.StartMZ.Text = 'Start m/z';
            app.EndMZ = uicheckbox(app.AddInfo);
            app.EndMZ.Text = 'End m/z';
            app.LowMZ = uicheckbox(app.AddInfo);
            app.LowMZ.Text = 'Low m/z';
            app.HighMZ = uicheckbox(app.AddInfo);
            app.HighMZ.Text = 'High m/z';
            app.BaseMZ = uicheckbox(app.AddInfo);
            app.BaseMZ.Text = 'Base Peak m/z';
            app.BaseInt = uicheckbox(app.AddInfo);
            app.BaseInt.Text = 'Base Peak Intensity';
            app.SelectAllADD = uicheckbox(app.AddInfo);
            app.SelectAllADD.ValueChangedFcn = createCallbackFcn(app, @SelectAllAdd, true);
            app.SelectAllADD.Text = 'Select All';
            app.TIC = uicheckbox(app.AddInfo);
            app.TIC.Text = 'Total Ion Current';
            app.PreScanNum = uicheckbox(app.AddInfo);
            app.PreScanNum.Text = 'Precursor Scan Num';
            app.Energy = uicheckbox(app.AddInfo);
            app.Energy.Text = 'Ionization Energy';
            app.Precision = uicheckbox(app.AddInfo);
            app.Precision.Text = 'Precision';
            app.Spectra = uicheckbox(app.AddInfo);
            app.Spectra.Text = 'Spectra';
            app.FilterLine = uicheckbox(app.AddInfo);
            app.FilterLine.Text = 'Filter Line';
            app.SelectXMLFileButton = uibutton(app.UIFigure, 'push');
            app.SelectXMLFileButton.ButtonPushedFcn = createCallbackFcn(app, @getXMLfile, true);
            app.SelectXMLFileButton.Text = 'Select XML File';
            app.XMLFileLocationLabel = uilabel(app.UIFigure);
            app.XMLFileLocationLabel.Text = 'XML File Location:';
            app.XMLFileLocationTextArea = uitextarea(app.UIFigure);
            app.OutputFileLocationTextAreaLabel = uilabel(app.UIFigure);
            app.OutputFileLocationTextAreaLabel.Text = 'Output File Location:';
            app.OutputFileLocationTextArea = uitextarea(app.UIFigure);
            app.SelectOutputButton = uibutton(app.UIFigure, 'push');
            app.SelectOutputButton.ButtonPushedFcn = createCallbackFcn(app, @selectOutput, true);
            app.SelectOutputButton.Text = 'Select Output';
            app.XMLFileReaderLabel = uilabel(app.UIFigure);
            app.XMLFileReaderLabel.Text = 'XML File Reader --------------------------------------------------------------------------';
            app.READButton = uibutton(app.UIFigure, 'push');
            app.READButton.ButtonPushedFcn = createCallbackFcn(app, @READButtonPushed, true);
            app.READButton.Text = 'READ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = xmlreader

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end