classdef PostAnalGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GlycoproteomicsPostAnalysisGUIPanel  matlab.ui.container.Panel
        StatusTextArea                 matlab.ui.control.TextArea
        StatusTextAreaLabel            matlab.ui.control.Label
        OutputfolderlocationTextArea   matlab.ui.control.TextArea
        OutputfolderlocationTextAreaLabel  matlab.ui.control.Label
        ScoredOglypepFileTextArea      matlab.ui.control.TextArea
        ScoredOglypepFileLabel         matlab.ui.control.Label
        ScoredNglypepFileTextArea      matlab.ui.control.TextArea
        ScoredNglypepFileLabel         matlab.ui.control.Label
        ScoredPepFileTextArea          matlab.ui.control.TextArea
        ScoredPepFileLabel             matlab.ui.control.Label
        OglyDigestTextArea             matlab.ui.control.TextArea
        OglyDigestTextAreaLabel        matlab.ui.control.Label
        NglyDigestTextArea             matlab.ui.control.TextArea
        NglyDigestTextAreaLabel        matlab.ui.control.Label
        SetOutputFolderButton          matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        OglycopeptideResultfileButton  matlab.ui.control.Button
        NglycopeptideResultfileButton  matlab.ui.control.Button
        PeptideResultfileButton        matlab.ui.control.Button
        LocateOglycandigestfileButton  matlab.ui.control.Button
        LocateNglycandigestfileButton  matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LocateNglycandigestfileButton
        function LocateNglycandigestfileButtonPushed(app, event)
            % Prompt the user to select a file
            [filename, pathname] = uigetfile({'*.txt', 'Text Files (*.txt)'}, 'Select a Text File');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('File selection canceled.');
                return;
            end

            % Construct the full file path
            fullFilePath = fullfile(pathname, filename);

            % Read the content of the text file
            try
                % Display the content in the Text Area
                app.NglyDigestTextArea.Value = fullFilePath;
            catch
                disp('Error: Could not read the text file.');
            end
        end

        % Button pushed function: LocateOglycandigestfileButton
        function LocateOglycandigestfileButtonPushed(app, event)
            % Prompt the user to select a file
            [filename, pathname] = uigetfile({'*.txt', 'Text Files (*.txt)'}, 'Select a Text File');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('File selection canceled.');
                return;
            end

            % Construct the full file path
            fullFilePath = fullfile(pathname, filename);

            % Read the content of the text file
            try
                % Display the content in the Text Area
                app.OglyDigestTextArea.Value = fullFilePath;
            catch
                disp('Error: Could not read the text file.');
            end
        end

        % Button pushed function: PeptideResultfileButton
        function PeptideResultfileButtonPushed(app, event)
            % Prompt the user to select a file
            [filename, pathname] = uigetfile({'*.xlsx', 'Excel Files (*.xlsx)'}, 'Select an Excel File');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('File selection canceled.');
                return;
            end

            % Construct the full file path
            fullFilePath = fullfile(pathname, filename);

            % Display the selected file's name in the Text Area
            app.ScoredPepFileTextArea.Value = fullFilePath;
        end

        % Button pushed function: NglycopeptideResultfileButton
        function NglycopeptideResultfileButtonPushed(app, event)
            % Prompt the user to select a file
            [filename, pathname] = uigetfile({'*.xlsx', 'Excel Files (*.xlsx)'}, 'Select an Excel File');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('File selection canceled.');
                return;
            end

            % Construct the full file path
            fullFilePath = fullfile(pathname, filename);

            % Display the selected file's name in the Text Area
            app.ScoredNglypepFileTextArea.Value = fullFilePath;
        end

        % Button pushed function: OglycopeptideResultfileButton
        function OglycopeptideResultfileButtonPushed(app, event)
            % Prompt the user to select a file
            [filename, pathname] = uigetfile({'*.xlsx', 'Excel Files (*.xlsx)'}, 'Select an Excel File');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('File selection canceled.');
                return;
            end

            % Construct the full file path
            fullFilePath = fullfile(pathname, filename);

            % Display the selected file's name in the Text Area
            app.ScoredOglypepFileTextArea.Value = fullFilePath;
        end

        % Button pushed function: SetOutputFolderButton
        function SetOutputFolderButtonPushed(app, event)
            % Prompt the user to select a file
            filepath = uigetdir([],'Set Output Folder');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            % Check if the user canceled the file selection
            if isequal(filepath, 0)
                disp('Folder selection canceled.');
                return;
            end

            % Display the selected file's name in the Text Area
            app.OutputfolderlocationTextArea.Value = filepath;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            statusstr = '';
            app.StatusTextArea.Value = statusstr;
            pause(0.001)
            elapsedTime = tic;

            statusstr1stline = sprintf('%s \n','Loading and Preparing Scored Data...');
            statusstr2ndline = sprintf('%s \n','Please wait . . .');
            statusstr3rdline = sprintf('%s \n','');
            statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
            app.StatusTextArea.Value = statusstr;
            
            % Read N-glycan List and N-glycopeptide List from input file
            fileID = fopen(string(app.NglyDigestTextArea.Value), 'r');

            % Check if the file was opened successfully
            if fileID == -1
                disp('Error: Could not open the file.');
                return;
            end
            lines = {};
            % Read the content of the file as a character array
            while ~feof(fileID)
                line = fgetl(fileID);
                lines = [lines; {line}];
            end
            matchingIndices = startsWith(lines, '#');   % find lines starting with in order to parse glycans and peptides
            idx=find(matchingIndices);
            temp = lines(idx(6)+2:idx(7)-1);
            NglyList=cell(length(temp),1);              % get rid of prefix and suffix from glycan list
            for i = 1: length(temp)
                idx2=strfind(temp{i},' ');
                NglyList{i} = {temp{i}(idx2(2)+1:idx2(3)-1)};
            end
            NpepList = lines(idx(13)+2:idx(14)-1);
            for i = 1: length(NpepList)                 % get rid of elements in {2} from peptide list
                      NpepList{i}={strrep(NpepList{i},'{2}','')};   
            end
            % Close the file
            fclose(fileID);

            % Read O-glycan List and O-glycopeptide List from input file
            fileID = fopen(string(app.OglyDigestTextArea.Value), 'r');
            if fileID == -1            % Check if the file was opened successfully
                disp('Error: Could not open the file.');
                return;
            end
            lines = {};
            while ~feof(fileID)        % Read the content of the file as a character array
                line = fgetl(fileID);
                lines = [lines; {line}];
            end
            matchingIndices = startsWith(lines, '#');   % find lines starting with in order to parse glycans and peptides
            idx=find(matchingIndices);
            temp = lines(idx(6)+2:idx(7)-1);
            OglyList=cell(length(temp),1);              % get rid of prefix and suffix from glycan list
            for i = 1: length(temp)
                idx2=strfind(temp{i},' ');
                OglyList{i} = temp{i}(idx2(2)+1:idx2(3)-1);
            end
            OpepList = lines(idx(13)+2:idx(14)-1);
            for i = 1: length(OpepList)                 % get rid of elements in {2} from peptide list
                      OpepList{i}=strrep(OpepList{i},'{2}','');   
            end
            OpepList = unique(OpepList);
            fclose(fileID);                 % Close the file
            
            OutputFolder = app.OutputfolderlocationTextArea.Value;
            Out_NGPfile = app.ScoredNglypepFileTextArea.Value;
            Out_OGPfile = app.ScoredOglypepFileTextArea.Value;
            Out_pep = readtable(string(app.ScoredPepFileTextArea.Value));
            opts_N = detectImportOptions(string(Out_NGPfile),'NumHeaderLines',28);% TH added
            Out_N = readtable(string(Out_NGPfile),opts_N);
            opts_O = detectImportOptions(string(Out_OGPfile),'NumHeaderLines',28);% TH added
            Out_O = readtable(string(Out_OGPfile),opts_O);

            statusstr1stline = sprintf('%s \n','Analyzing N-glycosylation data... (starting parallel computing)');
            statusstr2ndline = sprintf('%s \n','Please wait . . .');
            statusstr3rdline = sprintf('%s \n','');
            statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
            app.StatusTextArea.Value = statusstr;
            f = msgbox(["N-glycosylation parallel computing started";"Please Wait..."]);

            postAnalN(NpepList,NglyList,Out_pep,Out_N,OutputFolder,Out_NGPfile) %NpepList,NglyList,Out_pep,Out_N,Output Folder,NGlyInputFile
            statusstr1stline = sprintf('%s \n','Analyzing O-glycosylation data...');
            statusstr2ndline = sprintf('%s \n','Please wait . . .');
            statusstr3rdline = sprintf('%s \n','');
            statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
            app.StatusTextArea.Value = statusstr;
            postAnalO(OpepList,OglyList,Out_pep,Out_O,OutputFolder,Out_OGPfile)

            elapsedTime = toc;
            statusstr1stline = sprintf('%s \n','PostAnalysis of files completed');
            statusstr2ndline = sprintf('%s \n','Elapsed Time = ', elapsedTime);
            statusstr3rdline = sprintf('%s \n','');
            statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
            app.StatusTextArea.Value = statusstr;
            pause(0.001)
            close(f);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GlycoproteomicsPostAnalysisGUIPanel = uipanel(app.UIFigure);
            app.GlycoproteomicsPostAnalysisGUIPanel.Title = 'Glycoproteomics Post Analysis GUI';
            app.RunButton = uibutton(app.GlycoproteomicsPostAnalysisGUIPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run!';
            app.LocateNglycandigestfileButton = uibutton(app.GlycoproteomicsPostAnalysisGUIPanel, 'push');
            app.LocateNglycandigestfileButton.ButtonPushedFcn = createCallbackFcn(app, @LocateNglycandigestfileButtonPushed, true);
            app.LocateNglycandigestfileButton.Text = 'Locate N-glycan digest file ...';
            app.LocateOglycandigestfileButton = uibutton(app.GlycoproteomicsPostAnalysisGUIPanel, 'push');
            app.LocateOglycandigestfileButton.ButtonPushedFcn = createCallbackFcn(app, @LocateOglycandigestfileButtonPushed, true);
            app.LocateOglycandigestfileButton.Text = 'Locate O-glycan digest file ...';
            app.PeptideResultfileButton = uibutton(app.GlycoproteomicsPostAnalysisGUIPanel, 'push');
            app.PeptideResultfileButton.ButtonPushedFcn = createCallbackFcn(app, @PeptideResultfileButtonPushed, true);
            app.PeptideResultfileButton.Text = 'Peptide Result file ...';
            app.NglycopeptideResultfileButton = uibutton(app.GlycoproteomicsPostAnalysisGUIPanel, 'push');
            app.NglycopeptideResultfileButton.ButtonPushedFcn = createCallbackFcn(app, @NglycopeptideResultfileButtonPushed, true);
            app.NglycopeptideResultfileButton.Text = 'N-glycopeptide Result file ...';
            app.OglycopeptideResultfileButton = uibutton(app.GlycoproteomicsPostAnalysisGUIPanel, 'push');
            app.OglycopeptideResultfileButton.ButtonPushedFcn = createCallbackFcn(app, @OglycopeptideResultfileButtonPushed, true);
            app.OglycopeptideResultfileButton.Text = 'O-glycopeptide Result file ...';
            app.Label = uilabel(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.Label.Text = '';
            app.SetOutputFolderButton = uibutton(app.GlycoproteomicsPostAnalysisGUIPanel, 'push');
            app.SetOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetOutputFolderButtonPushed, true);
            app.SetOutputFolderButton.Text = 'Set Output Folder...';
            app.NglyDigestTextAreaLabel = uilabel(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.NglyDigestTextAreaLabel.Text = 'N-gly Digest:';
            app.NglyDigestTextArea = uitextarea(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.OglyDigestTextAreaLabel = uilabel(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.OglyDigestTextAreaLabel.Text = 'O-gly Digest:';
            app.OglyDigestTextArea = uitextarea(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.ScoredPepFileLabel = uilabel(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.ScoredPepFileLabel.Text = 'Scored Pep File:';
            app.ScoredPepFileTextArea = uitextarea(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.ScoredNglypepFileLabel = uilabel(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.ScoredNglypepFileLabel.Text = 'Scored N-glypep File:';
            app.ScoredNglypepFileTextArea = uitextarea(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.ScoredOglypepFileLabel = uilabel(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.ScoredOglypepFileLabel.Text = 'Scored O-glypep File:';
            app.ScoredOglypepFileTextArea = uitextarea(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.OutputfolderlocationTextAreaLabel = uilabel(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.OutputfolderlocationTextAreaLabel.Text = 'Output folder location:';
            app.OutputfolderlocationTextArea = uitextarea(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.StatusTextAreaLabel = uilabel(app.GlycoproteomicsPostAnalysisGUIPanel);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.GlycoproteomicsPostAnalysisGUIPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PostAnalGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end