classdef synglycangui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        synglycanfig                   matlab.ui.Figure
        scoreall                       matlab.ui.container.Panel
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        PostAnalysisButton             matlab.ui.control.Button
        pushbutton_browseresults_2     matlab.ui.control.Button
        StatusPanel                    matlab.ui.container.Panel
        StatusTextArea                 matlab.ui.control.TextArea
        pushbutton_scoreallfiles       matlab.ui.control.Button
        inputs                         matlab.ui.container.Panel
        SelectGlycanLibraryPanel       matlab.ui.container.Panel
        OGlycanDropDown                matlab.ui.control.DropDown
        OGlycanDropDownLabel           matlab.ui.control.Label
        NGlycanDropDown                matlab.ui.control.DropDown
        NGlycanDropDownLabel           matlab.ui.control.Label
        SelectProteasePanel            matlab.ui.container.Panel
        EnzymeDropDown                 matlab.ui.control.DropDown
        EnzymeDropDownLabel            matlab.ui.control.Label
        SelectOutputFileLocationPanel  matlab.ui.container.Panel
        edit_outputfilename            matlab.ui.control.TextArea
        pushbutton_outputfilename      matlab.ui.control.Button
        LoadaFASTAfilecontainingtheproteinsequencePanel  matlab.ui.container.Panel
        edit_loadedfastafile           matlab.ui.control.TextArea
        pushbutton_selectglylibfile    matlab.ui.control.Button
        SelectFixedPTMPanel            matlab.ui.container.Panel
        FixedPTMCysteineCarboxymethylationCheckBox  matlab.ui.control.CheckBox
        FixedPTMNoneCheckBox           matlab.ui.control.CheckBox
        SelectMassTolerancesPanel      matlab.ui.container.Panel
        MS1units                       matlab.ui.control.DropDown
        MS1                            matlab.ui.control.NumericEditField
        CIDMS2ToleranceLabel_2         matlab.ui.control.Label
        EThcD                          matlab.ui.control.NumericEditField
        ETDMS2ToleranceLabel           matlab.ui.control.Label
        EThcDunits                     matlab.ui.control.DropDown
        HCD                            matlab.ui.control.NumericEditField
        HCDMS2ToleranceLabel           matlab.ui.control.Label
        HCDunits                       matlab.ui.control.DropDown
        CID                            matlab.ui.control.NumericEditField
        CIDMS2ToleranceLabel           matlab.ui.control.Label
        CIDunits                       matlab.ui.control.DropDown
        uipanel_msdataload             matlab.ui.container.ButtonGroup
        edit_loadmatstatus             matlab.ui.control.TextArea
        pushbutton_selectmatfile       matlab.ui.control.Button
    end

    
    properties (Access = private)
        fastafile
        digestopt
        msdatafile
        resultpath
        resultfilepath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %listing = dir("ProgramFiles\dataFolder");
            listing = dir();
            allfiles = {listing.name};
            libs = allfiles(contains({listing.name},'allfragments'));
            nlibs = libs(contains(libs,'_N'));
            if isempty(nlibs)
                [files,path]=uigetfile('*.exe','Please locate N-glycan fragment .mat file');
                nlibs={fullfile(path,files)};
            end
            app.NGlycanDropDown.Items = nlibs;
            olibs = libs(contains(libs,'_O'));
            if isempty(olibs)
                [files,path]=uigetfile('*.exe','Please locate O-glycan fragment .mat file');
                olibs={fullfile(path,files)};
            end
            app.OGlycanDropDown.Items = olibs;
            %app.Ncheck.Value = 1;
            %app.Ocheck.Value = 1;
            app.NGlycanDropDown.Enable = 'on';
            app.OGlycanDropDown.Enable = 'on'; %changed off to on
        end

        % Button pushed function: pushbutton_selectglylibfile
        function pushbutton_selectglylibfileButtonPushed(app, event)
            [filename,pathname,~] = uigetfile({'*.fasta'},'Pick a text file storing protein sequences');
            app.synglycanfig.Visible = 'off';
            app.synglycanfig.Visible = 'on';
            if(isequal(filename,0) || isequal(pathname,0))
                msgbox('File NOT Selected');
                app.edit_loadedfastafile.Value = '';
            else
                try
                    app.fastafile = fullfile(pathname,filename);
                    app.edit_loadedfastafile.Value = app.fastafile;
                catch
                    errordlg('Please select file again');
                    app.edit_loadedfastafile.Value = '';
                    return
                end
            end
        end

        % Value changed function: edit_loadedfastafile
        function edit_loadedfastafileValueChanged(app, event)
            value = app.edit_loadedfastafile.Value;
            app.fastafile = value{1};
        end

        % Value changed function: FixedPTMNoneCheckBox
        function FixedPTMNoneCheckBoxValueChanged(app, event)
            value = app.FixedPTMNoneCheckBox.Value;
            if value == 1
                app.FixedPTMCysteineCarboxymethylationCheckBox.Value = 0;
            end
        end

        % Value changed function: 
        % FixedPTMCysteineCarboxymethylationCheckBox
        function FixedPTMCysteineCarboxymethylationCheckBoxValueChanged(app, event)
            value = app.FixedPTMCysteineCarboxymethylationCheckBox.Value;
            if value == 1
                app.FixedPTMNoneCheckBox.Value = 0;
            end
        end

        % Button pushed function: pushbutton_outputfilename
        function pushbutton_outputfilenameButtonPushed(app, event)
            pathname = uigetdir(path,'Save the output to a file location');
            app.synglycanfig.Visible = 'off';
            app.synglycanfig.Visible = 'on';
            if(isequal(pathname,0))
                msgbox('File Location NOT Selected');
                app.edit_outputfilename.Value = '';
            else
                try
                    app.edit_outputfilename.Value = pathname;
                    app.resultpath      = pathname;
                catch
                    errordlg('Please select file location again');
                    app.edit_outputfilename.Value = '';
                    return
                end
            end
        end

        % Value changed function: edit_outputfilename
        function edit_outputfilenameValueChanged(app, event)
            value = app.edit_outputfilename.Value;
            app.resultpath = value{1};
        end

        % Button pushed function: pushbutton_selectmatfile
        function pushbutton_selectmatfileButtonPushed(app, event)
            [matfilename, matpathname] = uigetfile('*.mat','Pick an experiment file','MultiSelect','on');
            app.synglycanfig.Visible = 'off';
            app.synglycanfig.Visible = 'on';
            if(isequal(matfilename,0) || isequal(matpathname,0))
                msgbox('File NOT Selected');
                app.edit_loadmatstatus.Value = '';
            else
                try
                    matfullfilename = fullfile(matpathname,matfilename);
                    app.edit_loadmatstatus.Value = matfullfilename;
                    app.msdatafile = matfullfilename;
                catch
                    errordlg('Please select file again');
                    app.edit_loadmatstatus.Value = '';
                    return
                end
            end
        end

        % Value changed function: edit_loadmatstatus
        function edit_loadmatstatusValueChanged(app, event)
            value = app.edit_loadmatstatus.Value;
            app.msdatafile = value{1};
        end

        % Callback function
        function NcheckValueChanged(app, event)
            value = app.Ncheck.Value;
            if value == 1
                app.Ocheck.Value = 1;
                %app.NGlycanDropDown.Enable = 'on';
                %app.OGlycanDropDown.Enable = 'off';
            else
                %app.NGlycanDropDown.Enable = 'off';
            end
        end

        % Callback function
        function OcheckValueChanged(app, event)
            value = app.Ocheck.Value;
            if value == 1
                app.Ncheck.Value = 1;
                %app.OGlycanDropDown.Enable = 'on';
                %app.NGlycanDropDown.Enable = 'off';
            else
                %app.OGlycanDropDown.Enable = 'off';
            end
        end

        % Button pushed function: pushbutton_scoreallfiles
        function pushbutton_scoreallfilesButtonPushed(app, event)
            statusstr = '';
            app.StatusTextArea.Value = statusstr;
            pause(0.001)
            elapsedTime = tic;
            
%% CHECK MAIN INPUTS
            if ~exist(app.fastafile,'file')
                error('Protein FASTA File Not Found.');
            end
            if iscell(app.msdatafile)
                for y = 1:size(app.msdatafile,2)
                    if ~exist(app.msdatafile{y},'file')
                        error('Experiment Data File Not Found.');
                    end
                end
            else
                if ~exist(app.msdatafile,'file')
                    error('Experiment Data File Not Found.');
                end
            end
            if ~exist(app.resultpath,"dir")
                error('Results Output Location Not Found.');
            end

%% LOAD PROTEIN FASTA FILE
            statusstr1stline = sprintf('%s \n','Loading Protein File');
            statusstr2ndline = sprintf('%s \n','Please wait . . .');
            statusstr3rdline = sprintf('%s \n','');
            statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
            app.StatusTextArea.Value = statusstr;
            pause(0.001)

            protein = fastaread(app.fastafile);

%% PREPARE TOLERANCES AND OTHER MS PARAMETERS
            statusstr1stline = sprintf('%s \n','Preparing User Inputs');
            statusstr2ndline = sprintf('%s \n','Please wait . . .');
            statusstr3rdline = sprintf('%s \n','');
            statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
            app.StatusTextArea.Value = statusstr;
            pause(0.001)

            %User input
            ms1tol = app.MS1.Value;
            ms1tolunit = app.MS1units.Value;
            allfragmodes = {'CID','HCD','ETHCD','ETCID','ETD'};
            allms2tol = [app.CID.Value;app.HCD.Value;app.EThcD.Value;...
                         app.EThcD.Value;app.EThcD.Value];
            allms2tolunit = {app.CIDunits.Value;app.HCDunits.Value;app.EThcDunits.Value;...
                             app.EThcDunits.Value;app.EThcDunits.Value};
            allcutoffmed = [4;0;0;0;0];
            allfracmax = [.05;.02;.02;.02;.02];
            allfragnum = [0 2 0;1 2 0;1 1 0;1 1 0;1 0 0];
            allfragiontyp = logical([1 0 1 0 0;1 0 1 0 0;1 1 1 1 0;... %'CID','HCD','ETHCD'
                                     0 1 0 1 0;0 1 0 1 0]);
            allmonosacislabile = [true;true;true;true;true];
            alladdoxoniumion = [false;true;true;false;false];
            
%% PREPARE PROTEASE SELECTION AND DIGESTION PARAMETERS
            drop_enzname = app.EnzymeDropDown.Value;  % Retrieve user selection
            split_enzname = regexp(drop_enzname,'\&','split');  % User may select a mixture of 
            %     proteases, then split the text into independent protease names
            % Remove leading and trailing whitespaces
            if(length(split_enzname) == 1)  % 1 protease selected
                enzname = strtrim(split_enzname{1});
            elseif(length(split_enzname)>1)  % > 1 proteases
                enzname = cell(length(split_enzname),1);
                for i = 1: length(split_enzname)
                    enzname{i} = strtrim(split_enzname{i});
                end
            end

            digestoptions.minpeplen = 2;
            digestoptions.maxpeplen = 35;
            digestoptions.minptm = 0;
            digestoptions.missedmax = 1;
            if app.FixedPTMNoneCheckBox.Value
                digestoptions.fixedptm = 0;
                %{
                digestoptions.fixedptm.aaresidue = 'X';
                digestoptions.fixedptm.new = 'X';
                digestoptions.fixedptm.protpos = 0;
                digestoptions.fixedptm.mod = ' ';
                %}
            elseif app.FixedPTMCysteineCarboxymethylationCheckBox.Value
                digestoptions.fixedptm.aaresidue = 'C';
                digestoptions.fixedptm.new = 'C<i>';
                digestoptions.fixedptm.protpos = 0;
                digestoptions.fixedptm.mod = 'i';
            end
            digestoptions.dispprog = 0;
            digestoptions.protfilepath = '';
            digestoptions.doparacomp = 1;

%% PREPARE SCORING INPUTS
            input.ms1tol = ms1tol;
            input.ms1tolunit = ms1tolunit;
            input.minmaxmz = [0,Inf];
            input.outputdir = app.resultpath;
            input.maxlag = 25;
            input.selectpeak = [];
            input.numprotperworker = 100;
            input.maxstublen = 2;
            input.maxOglyonpep = 5;
            input.excludeNglysequon = true;
            input.presearch = false;
            input.presearchtopprotnum = 1000;
            input.resumeprevsearch = false;
            input.doparacomp = 1;
            input.fptm = app.FixedPTMCysteineCarboxymethylationCheckBox.Value;


%% PROCESS EXPERIMENTAL DATA
%% MULTIPLE FILES: PROCESS EXPERIMENTAL DATA
            if iscell(app.msdatafile) %For when running multiple raw files consecutively
                for x = 1:size(app.msdatafile,2)
                    [msdatapath,msdataname,ext] = fileparts(app.msdatafile{x});
                    statusstr1stline = sprintf('%s %s %s\n','Converting experimental data file #',string(x),' from provided format to QA.mat and trigger.mat files');
                    statusstr2ndline = sprintf('%s \n','Please wait . . .');
                    statusstr3rdline = sprintf('%s \n','');
                    statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
                    app.StatusTextArea.Value = statusstr;
                    pause(0.001)
                    switch lower(ext)
                        case '.mat'
                            msdataname = msdataname(1:end-3);
                            msdata_test = load(app.msdatafile{x});
                            if ~isfield(msdata_test,'msdata_QA')
                                msdata_mat = load(app.msdatafile{x},'msdata');
                                msdata_QA = msdata_mat.msdata;
                                default_isodist_mat = load('default_iso_dist.mat','defaultisodist');
                                default_isodist = default_isodist_mat.defaultisodist;
                                chnos = [0.0411425801242450,0.0667414095012345,0.00760993965077790,0.0203467465280654,0.000219515203030292]; %GLYCOPEPTIDE CHNOS VALUES
                                msdata_QA = defaultmonoiso(msdata_QA.scannum,msdata_QA,default_isodist,chnos,0);
                                quant_ms2tols = {app.CID.Value,app.CIDunits.Value;app.HCD.Value,app.HCDunits.Value;app.EThcD.Value,...
                                         app.EThcDunits.Value;app.EThcD.Value,app.EThcDunits.Value;app.EThcD.Value,app.EThcDunits.Value};
                                msdata_QA = quantitativeAnalysis(msdata_QA.scannum,msdata_QA,ms1tol,ms1tolunit,...
                                                                 quant_ms2tols,0.6,0.2,1,[],true,false,0,0);
                                msdatafilepath = fullfile(app.resultpath,[msdataname,'_QA.mat']);
                                n = msgbox('Saving QA.mat file...');
                                save(msdatafilepath,'msdata_QA','-v7.3');
                                close(n);
                                options.mode = 'PID';
                                options.triggermode = 'HCD';
                                analy_values = {'CID','HCD','ETD','ETCID','ETHCD'};
                                options.analymode = analy_values;
                                [SASSO,colnames] = buildm2sasso(msdata_QA,options);  % Build association between triggered scan
                                sassofilepath = fullfile(app.resultpath,[msdataname,'_QA_trigger.mat']);
                                n = msgbox('Saving trigger.mat file...');
                                save(sassofilepath,'SASSO','colnames');
                                close(n);
                            else
                                msdatafilepath = fullfile(app.resultpath,[msdataname]);
                                msdata_QA = msdata_test.msdata_QA;
                                try
                                    sassofilepath = fullfile(msdatapath,[msdataname,'_trigger.mat']);
                                    sasso_mat = load(sassofilepath);
                                    SASSO = sasso_mat.SASSO;
                                    colnames = sasso_mat.colnames;
                                    msdatafilepath = fullfile(app.resultpath,[msdataname]);
                                catch
                                    options.mode = 'PID';
                                    options.triggermode = 'HCD';
                                    analy_values = {'CID','HCD','ETD','ETCID','ETHCD'};
                                    options.analymode = analy_values;
                                    [SASSO,colnames] = buildm2sasso(msdata_QA,options);  % Build association between triggered scan
                                    sassofilepath = fullfile(app.resultpath,[msdataname,'_QA_trigger.mat']);
                                    n = msgbox('Saving trigger.mat file...');
                                    save(sassofilepath,'SASSO','colnames');
                                    close(n);
                                end
                            end
                        case {'.mzml','.raw'}
                            infos = {'std','totIonCurrent','precursorScanNum','spectra'};
                            msdata_QA = getmsdata(app.msdatafile{x},infos,app.msdatafile{x});
                            fragmodes = msdata_QA.fragmode;
                            requiredmodes = {'HCD','CID','EThcD'};
                            requiredmodesTF = arrayfun( @(pat)(any(contains(fragmodes,pat))), requiredmodes(:),'UniformOutput',false);
                            for i = 1:length(requiredmodesTF)
                                if requiredmodesTF{i} < 1
                                    error(['The .raw input file does contain HCD, CID and EThcD data. Please Load correct file']);
                                end
                            end
                            default_isodist_mat = load('default_iso_dist.mat','defaultisodist');
                            default_isodist = default_isodist_mat.defaultisodist;
                            chnos = [0.0411425801242450,0.0667414095012345,0.00760993965077790,0.0203467465280654,0.000219515203030292]; %GLYCOPEPTIDE CHNOS VALUES
                            msdata_QA = defaultmonoiso(msdata_QA.scannum,msdata_QA,default_isodist,chnos,0);
                            quant_ms2tols = {app.CID.Value,app.CIDunits.Value;...
                                app.HCD.Value,app.HCDunits.Value;...
                                app.EThcD.Value,app.EThcDunits.Value;...
                                app.EThcD.Value,app.EThcDunits.Value;...
                                app.EThcD.Value,app.EThcDunits.Value};
                            msdata_QA = quantitativeAnalysis(msdata_QA.scannum,msdata_QA,ms1tol,ms1tolunit,...
                                                             quant_ms2tols,0.6,0.2,1,[],true,false,0,0);
                            msdatafilepath = fullfile(app.resultpath,[msdataname,'_QA.mat']);
                            n = msgbox('Saving QA.mat file...');
                            save(msdatafilepath,'msdata_QA','-v7.3');
                            close(n);
                            options.mode = 'PID';
                            options.triggermode = 'HCD';
                            analy_values = {'CID','HCD','ETD','ETCID','ETHCD'};
                            options.analymode = analy_values;
                            [SASSO,colnames] = buildm2sasso(msdata_QA,options);  % Build association between triggered scan
                            sassofilepath = fullfile(app.resultpath,[msdataname,'_QA_trigger.mat']);
                            n = msgbox('Saving trigger.mat file...');
                            save(sassofilepath,'SASSO','colnames');
                            close(n);
                    end
                    for i = 1:3 %1 = N-pep search, 2 = N-glycopep search, 3 = O-glycopep search
%% MULTIPLE FILES: N-GLYCOPEPTIDE: ADJUST LIBRARY PARAMETERS BASED ON SEARCH TYPE
                        if i == 1 
                            selectlib = app.NGlycanDropDown.Value;
                            % substr = extractBetween(selectlib,'_','.');
                            % varfile = strjoin(['ProgramData/VPTM_',substr,'.txt'],'');
                            % varptm = getNSynglycans(varfile);
                            allfragments_mat = load(selectlib);
                            varptm = getNSynglycans(allfragments_mat.allfragments.ptmseq);
                            allfragments = allfragments_mat.allfragments;
                            allsimultaneousfrag = [false;false;false;false;false];
                            digestoptions.varptm = varptm;
                            digestoptions.maxptm = 2;
                            input.allfragments = allfragments;
                            input.analyzewhichglycan = 'N';
%% MULTIPLE FILES: O-GLYCOPEPTIDE: ADJUST LIBRARY PARAMETERS BASED ON SEARCH TYPE
                        elseif i == 3
                            selectlib = app.OGlycanDropDown.Value;
                            % substr = extractBetween(selectlib,'_','.');
                            % varfile = strjoin(['ProgramData/VPTM_',substr,'.txt'],'');
                            allfragments_mat = load(selectlib);
                            varptm = getOSynglycans(allfragments_mat.allfragments.ptmseq);
                            allfragments = allfragments_mat.allfragments;
                            allsimultaneousfrag = [false;true;true;true;false];
                            digestoptions.varptm = varptm;
                            digestoptions.maxptm = 5;
                            input.allfragments = allfragments;
                            input.analyzewhichglycan = 'O';
                        end

 %% MULTIPLE FILES: PREPARE INPUTS BASED ON PROCESSED EXPERIMENTAL DATA
                        availablefragmodes = unique(msdata_QA.fragmode);
                        availablefragmodes = availablefragmodes(~cellfun(@isempty,availablefragmodes));
                        availablefragmodeind = ismember(allfragmodes,upper(availablefragmodes))&([1,1,1,1,1]);
                        if i == 1
                            availablefragmodeind = logical([0,1,0,0,0]);
                            input.analyzetgt = 2; %peptide
                            input.dohcdtrigger = 0;
                        elseif i == 2 || i == 3
                            input.analyzetgt = 1; %glycopeptide
                            input.dohcdtrigger = 1;
                        end
                        todofragmodes = allfragmodes(availablefragmodeind); %CID, HCD, EThcD
                        todoms2tol = allms2tol(availablefragmodeind);
                        todoms2tolunit = allms2tolunit(availablefragmodeind);
                        todocutoffmed = allcutoffmed(availablefragmodeind);
                        todofracmax = allfracmax(availablefragmodeind);
                        todofragnum = allfragnum(availablefragmodeind,:);
                        todofragiontyp = allfragiontyp(availablefragmodeind,:);
                        todomonosacislabile = allmonosacislabile(availablefragmodeind);
                        todosimultaneousfrag = allsimultaneousfrag(availablefragmodeind);
                        todoaddoxoniumion = alladdoxoniumion(availablefragmodeind);
                    
                        input.peporglycopep = i;
                        input.fragmode = todofragmodes;
                        input.ms2tol = todoms2tol;
                        input.ms2tolunit = todoms2tolunit;
                        input.cutoffmed = todocutoffmed;
                        input.fracmax = todofracmax;
                        input.fragnum = todofragnum;
                        input.fragiontyp = todofragiontyp;
                        input.monosacislabile = todomonosacislabile;
                        input.simultaneousfrag = todosimultaneousfrag;
                        input.addoxoniumion = todoaddoxoniumion;
                        input.exptdata = msdatafilepath;
                        input.SASSO = SASSO;
                        input.colnames = colnames;
                        input.outputfname = [msdataname,'.mat']; %remove [,'_bestisomers.mat']
                        
%% MULTIPLE FILES: PERFORM DIGESTION
                        if i == 1 || i == 3
                            if i == 1
                                statusstr1stline = sprintf('%s \n','Performing N-glycopeptide digestion');
                                statusstr2ndline = sprintf('%s \n','Please wait . . .');
                                statusstr3rdline = sprintf('%s \n','');
                                statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
                                app.StatusTextArea.Value = statusstr;
                                pause(0.001)
                            elseif i == 3
                                statusstr1stline = sprintf('%s \n','Performing O-glycopeptide digestion');
                                statusstr2ndline = sprintf('%s \n','Please wait . . .');
                                statusstr3rdline = sprintf('%s \n','');
                                statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
                                app.StatusTextArea.Value = statusstr;
                                pause(0.001)
                            end
                            [~,digestionoutput,~] = digestSGP({protein.sequence},protein.header,enzname,digestoptions);
                            digestionoutput_lines=splitlines(string(digestionoutput));
                            for k = 1:size(digestionoutput_lines,1)
                                if strcmp(digestionoutput_lines(k),'#SEQUENCES')
                                    pepfragstart = k+2;
                                elseif strcmp(digestionoutput_lines(k),'#END')
                                    pepfragend = k;
                                elseif strcmp(digestionoutput_lines(k),'#PROTSEQPATH')
                                    pepseqpathline = k + 1;
                                end
                            end
                            pepseqnum = pepfragend - pepfragstart;
                            Ppep={};
                            Gpep={};
                            for j = 1:pepseqnum
                                if ~contains(digestionoutput_lines(pepfragstart+j-1),'{2}')
                                    Ppep=[Ppep;digestionoutput_lines(pepfragstart+j-1,:)];
                                else
                                    Gpep=[Gpep;digestionoutput_lines(pepfragstart+j-1,:)];
                                end
                            end
                            digest_top = digestionoutput_lines(1:pepfragstart-1);
                            digest_Gpep = digest_top;
                            digest_Ppep = digest_top;
                            digest_Gpep(pepseqpathline) = app.fastafile;
                            digest_Ppep(pepseqpathline) = app.fastafile;
                            digest_Gpep(3) = cellstr(append('Generated peptide: ',num2str(size(Gpep,1))));
                            digest_Ppep(3) = cellstr(append('Generated peptide: ',num2str(size(Ppep,1))));
                            endcell = "#END";
                            digest_Gpep = [digest_Gpep;Gpep;endcell];
                            digest_Ppep = [digest_Ppep;Ppep;endcell];
                            if i == 1
                                outputfilefullname_Gpep = fullfile(app.resultpath,[protein.header,'_N_glycopep_digest.txt']);
                                outputfilefullname_Ppep = fullfile(app.resultpath,[protein.header,'_N_pep_digest.txt']);
                            elseif i == 3
                                outputfilefullname_Gpep = fullfile(app.resultpath,[protein.header,'_O_glycopep_digest.txt']);
                            %    outputfilefullname_Ppep = fullfile(app.resultpath,[protein.header,'_O_pep_digest.txt']);
                            end
                            fileID_Gpep = fopen(outputfilefullname_Gpep,'w');
                            fprintf(fileID_Gpep,"%s\n",digest_Gpep);
                            fclose(fileID_Gpep);
                            if i == 1
                                fileID_Ppep = fopen(outputfilefullname_Ppep,'w');
                                fprintf(fileID_Ppep,"%s\n",digest_Ppep);
                                fclose(fileID_Ppep);
                            end
                        end

                        if i == 1
                            input.pepfile = outputfilefullname_Ppep;
                        elseif i == 2 || i == 3
                            input.pepfile = outputfilefullname_Gpep;
                        end
%% MULTIPLE FILES: PERFORM SCORING
                        if i == 1
                            statusstr1stline = sprintf('%s %s \n','Performing N-pep scoring of file #', string(x));
                        elseif i == 2
                            statusstr1stline = sprintf('%s %s \n','Performing N-glycopep scoring of file #', string(x));
                        elseif i == 3
                            statusstr1stline = sprintf('%s %s \n','Performing O-glycopep scoring of file #', string(x));
                        end
                        statusstr2ndline = sprintf('%s \n','Please wait...');
                        statusstr3rdline = sprintf('%s \n','');
                        statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
                        app.StatusTextArea.Value = statusstr;
                        pause(0.001)
                        scoreAllSpectraSynGlycan(msdata_QA,input); %Generates 1_1 file
                        %{
                        if input.analyzetgt == 2 %
                            app.resultfilepath = fullfile(app.resultpath,[msdataname,'_pep_1_1.mat']);
                            fix_smallpep_enscore_script(app.resultfilepath,4);
                        elseif input.analyzetgt == 1
                            app.resultfilepath = fullfile(app.resultpath,[msdataname,'_glycopep_1_1.mat']);
                            fix_smallpep_enscore_script(app.resultfilepath,4);
                            app.resultfilepath = fullfile(app.resultpath,[msdataname,'_glycopep_bestisomers.mat']);
                            fix_smallpep_enscore_script(app.resultfilepath,4);
                        end
                        %}
                    end
%% MULTIPLE FILES: SCORING COMPLETED               
                    elapsedTime = toc;
                    statusstr1stline = sprintf('%s %s %s \n','Scoring of file #', string(x), 'completed');
                    statusstr2ndline = sprintf('%s \n','Elapsed Time = ', elapsedTime);
                    statusstr3rdline = sprintf('%s \n','');
                    statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
                    app.StatusTextArea.Value = statusstr;
                    pause(0.001)
                end
%% SINGLE FILE: PROCESS EXPERIMENTAL DATA
            else %For when running single raw file
                [msdatapath,msdataname,ext] = fileparts(app.msdatafile);
                statusstr1stline = sprintf('%s \n','Processing Experimental Data');
                statusstr2ndline = sprintf('%s \n','Please wait . . .');
                statusstr3rdline = sprintf('%s \n','');
                statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
                app.StatusTextArea.Value = statusstr;
                pause(0.001)
                switch lower(ext)
                    case '.mat'
                        msdataname = msdataname(1:end-3);
                        msdata_test = load(app.msdatafile);
                        if ~isfield(msdata_test,'msdata_QA')
                            msdata_mat = load(app.msdatafile,'msdata');
                            msdata_QA = msdata_mat.msdata;
                            default_isodist_mat = load('default_iso_dist.mat','defaultisodist');
                            default_isodist = default_isodist_mat.defaultisodist;
                            chnos = [0.0411425801242450,0.0667414095012345,0.00760993965077790,0.0203467465280654,0.000219515203030292]; %GLYCOPEPTIDE CHNOS VALUES
                            msdata_QA = defaultmonoiso(msdata_QA.scannum,msdata_QA,default_isodist,chnos,0);
                            quant_ms2tols = {app.CID.Value,app.CIDunits.Value;app.HCD.Value,app.HCDunits.Value;app.EThcD.Value,...
                                     app.EThcDunits.Value;app.EThcD.Value,app.EThcDunits.Value;app.EThcD.Value,app.EThcDunits.Value};
                            msdata_QA = quantitativeAnalysis(msdata_QA.scannum,msdata_QA,ms1tol,ms1tolunit,...
                                                             quant_ms2tols,0.6,0.2,1,[],true,false,0,0);
                            msdatafilepath = fullfile(app.resultpath,[msdataname,'_QA.mat']);
                            n = msgbox('Saving QA.mat file...');
                            save(msdatafilepath,'msdata_QA','-v7.3');
                            close(n);
                            options.mode = 'PID';
                            options.triggermode = 'HCD';
                            analy_values = {'CID','HCD','ETD','ETCID','ETHCD'};
                            options.analymode = analy_values;
                            [SASSO,colnames] = buildm2sasso(msdata_QA,options);  % Build association between triggered scan
                            sassofilepath = fullfile(app.resultpath,[msdataname,'_QA_trigger.mat']);
                            n = msgbox('Saving trigger.mat file...');
                            save(sassofilepath,'SASSO','colnames');
                            close(n);
                        else
                            msdatafilepath = fullfile(app.resultpath,[msdataname]);
                            msdata_QA = msdata_test.msdata_QA;
                            try
                                sassofilepath = fullfile(msdatapath,[msdataname,'_trigger.mat']);
                                sasso_mat = load(sassofilepath);
                                SASSO = sasso_mat.SASSO;
                                colnames = sasso_mat.colnames;
                                msdatafilepath = fullfile(app.resultpath,[msdataname]);
                            catch
                                options.mode = 'PID';
                                options.triggermode = 'HCD';
                                analy_values = {'CID','HCD','ETD','ETCID','ETHCD'};
                                options.analymode = analy_values;
                                [SASSO,colnames] = buildm2sasso(msdata_QA,options);  % Build association between triggered scan
                                sassofilepath = fullfile(app.resultpath,[msdataname,'_QA_trigger.mat']);
                                n = msgbox('Saving trigger.mat file...');
                                save(sassofilepath,'SASSO','colnames');
                                close(n);
                            end
                        end
                    case {'.mzml','.raw'}
                        infos = {'std','totIonCurrent','precursorScanNum','spectra'};
                        msdata_QA = getmsdata(app.msdatafile,infos,app.msdatafile);
                        fragmodes = msdata_QA.fragmode;
                        requiredmodes = {'HCD','CID','EThcD'};
                        requiredmodesTF = arrayfun( @(pat)(any(contains(fragmodes,pat))), requiredmodes(:),'UniformOutput',false);
                        for i = 1:length(requiredmodesTF)
                            if requiredmodesTF{i} < 1
                                error(['The .raw input file does contain HCD, CID and EThcD data. Please Load correct file']);
                            end
                        end
                        default_isodist_mat = load('default_iso_dist.mat','defaultisodist');
                        default_isodist = default_isodist_mat.defaultisodist;
                        chnos = [0.0411425801242450,0.0667414095012345,0.00760993965077790,0.0203467465280654,0.000219515203030292]; %GLYCOPEPTIDE CHNOS VALUES
                        msdata_QA = defaultmonoiso(msdata_QA.scannum,msdata_QA,default_isodist,chnos,0);
                        quant_ms2tols = {app.CID.Value,app.CIDunits.Value;...
                            app.HCD.Value,app.HCDunits.Value;...
                            app.EThcD.Value,app.EThcDunits.Value;...
                            app.EThcD.Value,app.EThcDunits.Value;...
                            app.EThcD.Value,app.EThcDunits.Value};
                        msdata_QA = quantitativeAnalysis(msdata_QA.scannum,msdata_QA,ms1tol,ms1tolunit,...
                                                         quant_ms2tols,0.6,0.2,1,[],true,false,0,0);
                        msdatafilepath = fullfile(app.resultpath,[msdataname,'_QA.mat']);
                        n = msgbox('Saving QA.mat file...');
                        save(msdatafilepath,'msdata_QA','-v7.3');
                        close(n);
                        options.mode = 'PID';
                        options.triggermode = 'HCD';
                        analy_values = {'CID','HCD','ETD','ETCID','ETHCD'};
                        options.analymode = analy_values;
                        [SASSO,colnames] = buildm2sasso(msdata_QA,options);  % Build association between triggered scan
                        sassofilepath = fullfile(app.resultpath,[msdataname,'_QA_trigger.mat']);
                        n = msgbox('Saving trigger.mat file...');
                        save(sassofilepath,'SASSO','colnames');
                        close(n);
                end

                for i = 1:3 %1 = N-pep search, 2 = N-glycopep search, 3 = O-glycopep search
%% SINGLE FILE: N-GLYCOPEPTIDE: ADJUST LIBRARY PARAMETERS BASED ON SEARCH TYPE
                    if i == 1
                        selectlib = app.NGlycanDropDown.Value;
%                        substr = extractBetween(selectlib,'_','.');
%                        varfile = strjoin(['ProgramData/VPTM_',substr,'.txt'],'');   
%                        varptm = getNSynglycans(varfile);
                        allfragments_mat = load(selectlib);
                        varptm = getNSynglycans(allfragments_mat.allfragments.ptmseq);
                        allfragments = allfragments_mat.allfragments;
                        allsimultaneousfrag = [false;false;false;false;false];
                        digestoptions.varptm = varptm;
                        digestoptions.maxptm = 2;
                        input.allfragments = allfragments;
                        input.analyzewhichglycan = 'N';

%% SINGLE FILE: O-GLYCOPEPTIDE: ADJUST LIBRARY PARAMETERS BASED ON SEARCH TYPE
                    elseif i == 3
                        selectlib = app.OGlycanDropDown.Value;
%                        substr = extractBetween(selectlib,'_','.');
%                        varfile = strjoin(['ProgramData/VPTM_',substr,'.txt'],'');
                        allfragments_mat = load(selectlib);
                        varptm = getOSynglycans(allfragments_mat.allfragments.ptmseq);
                        allfragments = allfragments_mat.allfragments;
                        allsimultaneousfrag = [false;true;true;true;false];
                        digestoptions.varptm = varptm;
                        digestoptions.maxptm = 5;
                        input.allfragments = allfragments;
                        input.analyzewhichglycan = 'O';
                    end
%% SINGLE FILE: PREPARE INPUTS BASED ON PROCESSED EXPERIMENTAL DATA
                    availablefragmodes = unique(msdata_QA.fragmode);
                    availablefragmodes = availablefragmodes(~cellfun(@isempty,availablefragmodes));
                    availablefragmodeind = ismember(allfragmodes,upper(availablefragmodes))&([1,1,1,1,1]);
                    if i == 1
                        availablefragmodeind = logical([0,1,0,0,0]);
                        input.analyzetgt = 2; %peptide
                        input.dohcdtrigger = 0;
                    elseif i == 2 || i == 3
                        input.analyzetgt = 1; %glycopeptide
                        input.dohcdtrigger = 1;
                    end
                    todofragmodes = allfragmodes(availablefragmodeind); %CID, HCD, EThcD
                    todoms2tol = allms2tol(availablefragmodeind);
                    todoms2tolunit = allms2tolunit(availablefragmodeind);
                    todocutoffmed = allcutoffmed(availablefragmodeind);
                    todofracmax = allfracmax(availablefragmodeind);
                    todofragnum = allfragnum(availablefragmodeind,:);
                    todofragiontyp = allfragiontyp(availablefragmodeind,:);
                    todomonosacislabile = allmonosacislabile(availablefragmodeind);
                    todosimultaneousfrag = allsimultaneousfrag(availablefragmodeind);
                    todoaddoxoniumion = alladdoxoniumion(availablefragmodeind);
                
                    input.peporglycopep = i;
                    input.fragmode = todofragmodes;
                    input.ms2tol = todoms2tol;
                    input.ms2tolunit = todoms2tolunit;
                    input.cutoffmed = todocutoffmed;
                    input.fracmax = todofracmax;
                    input.fragnum = todofragnum;
                    input.fragiontyp = todofragiontyp;
                    input.monosacislabile = todomonosacislabile;
                    input.simultaneousfrag = todosimultaneousfrag;
                    input.addoxoniumion = todoaddoxoniumion;
                    input.exptdata = msdatafilepath;
                    input.SASSO = SASSO;
                    input.colnames = colnames;
                    input.outputfname = [msdataname,'.mat']; %remove [,'_bestisomers.mat']
%% SINGLE FILE: PERFORM DIGESTION
                    if i == 1 || i == 3
                        if i == 1
                            statusstr1stline = sprintf('%s \n','Performing N-glycopeptide digestion');
                            statusstr2ndline = sprintf('%s \n','Please wait . . .');
                            statusstr3rdline = sprintf('%s \n','');
                            statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
                            app.StatusTextArea.Value = statusstr;
                            pause(0.001)
                        elseif i == 3
                            statusstr1stline = sprintf('%s \n','Performing O-glycopeptide digestion');
                            statusstr2ndline = sprintf('%s \n','Please wait . . .');
                            statusstr3rdline = sprintf('%s \n','');
                            statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
                            app.StatusTextArea.Value = statusstr;
                            pause(0.001)
                        end
                        [~,digestionoutput,~] = digestSGP({protein.sequence},protein.header,enzname,digestoptions);
                        digestionoutput_lines=splitlines(string(digestionoutput));
                        for k = 1:size(digestionoutput_lines,1)
                            if strcmp(digestionoutput_lines(k),'#SEQUENCES')
                                pepfragstart = k+2;
                            elseif strcmp(digestionoutput_lines(k),'#END')
                                pepfragend = k;
                            elseif strcmp(digestionoutput_lines(k),'#PROTSEQPATH')
                                pepseqpathline = k + 1;
                            end
                        end
                        pepseqnum = pepfragend - pepfragstart;
                        Ppep={};
                        Gpep={};
                        for j = 1:pepseqnum
                            if ~contains(digestionoutput_lines(pepfragstart+j-1),'{2}')
                                Ppep=[Ppep;digestionoutput_lines(pepfragstart+j-1,:)];
                            else
                                Gpep=[Gpep;digestionoutput_lines(pepfragstart+j-1,:)];
                            end
                        end
                        digest_top = digestionoutput_lines(1:pepfragstart-1);
                        digest_Gpep = digest_top;
                        digest_Ppep = digest_top;
                        digest_Gpep(pepseqpathline) = app.fastafile;
                        digest_Ppep(pepseqpathline) = app.fastafile;
                        digest_Gpep(3) = cellstr(append('Generated peptide: ',num2str(size(Gpep,1))));
                        digest_Ppep(3) = cellstr(append('Generated peptide: ',num2str(size(Ppep,1))));
                        endcell = "#END";
                        digest_Gpep = [digest_Gpep;Gpep;endcell];
                        digest_Ppep = [digest_Ppep;Ppep;endcell];
                        if i == 1
                            outputfilefullname_Gpep = fullfile(app.resultpath,[protein.header,'_N_glycopep_digest.txt']);
                            outputfilefullname_Ppep = fullfile(app.resultpath,[protein.header,'_N_pep_digest.txt']);
                        elseif i == 3
                            outputfilefullname_Gpep = fullfile(app.resultpath,[protein.header,'_O_glycopep_digest.txt']);
                            outputfilefullname_Ppep = fullfile(app.resultpath,[protein.header,'_O_pep_digest.txt']);
                        end
                        fileID_Gpep = fopen(outputfilefullname_Gpep,'w');
                        fprintf(fileID_Gpep,"%s\n",digest_Gpep);
                        fclose(fileID_Gpep);
                        if i ==1
                            fileID_Ppep = fopen(outputfilefullname_Ppep,'w');
                            fprintf(fileID_Ppep,"%s\n",digest_Ppep);
                            fclose(fileID_Ppep);
                        end
                    end

                    if i == 1
                        input.pepfile = outputfilefullname_Ppep;
                    elseif i == 2 || i == 3
                        input.pepfile = outputfilefullname_Gpep;
                    end
                    if i == 1
                        statusstr1stline = sprintf('%s \n','Performing Peptide Scoring');
                    elseif i == 2
                        statusstr1stline = sprintf('%s \n','Performing N-glycopeptide Scoring');
                    elseif i == 3
                        statusstr1stline = sprintf('%s \n','Performing O-glycopep Scoring');
                    end
                    statusstr2ndline = sprintf('%s \n','Please wait . . .');
                    statusstr3rdline = sprintf('%s \n','');
                    statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
                    app.StatusTextArea.Value = statusstr;
                    pause(0.001)
%% SINGLE FILE: PERFORM SCORING
                    scoreAllSpectraSynGlycan(msdata_QA,input); %Generates 1_1 file
                end
%% SINGLE FILE: SCORING COMPLETED               
                elapsedTime = toc;
                statusstr1stline = sprintf('%s \n','Scoring of file completed');
                statusstr2ndline = sprintf('%s \n','Elapsed Time = ', elapsedTime);
                statusstr3rdline = sprintf('%s \n','');
                statusstr = [statusstr,statusstr1stline,statusstr2ndline,statusstr3rdline];
                app.StatusTextArea.Value = statusstr;
                pause(0.001)
            end
        end

        % Button pushed function: pushbutton_browseresults_2
        function pushbutton_browseresults_2ButtonPushed(app, event)
            browsegui;
        end

        % Button pushed function: PostAnalysisButton
        function PostAnalysisButtonPushed(app, event)
            PostAnalGUI;
        end

        % Callback function
        function DoAllstepsButtonPushed(app, event)
            % Trigger all run files and get outputs
            pushbutton_scoreallfilesButtonPushed(app, event)
            a=1
            % Trim for FDR<1 and/or ES>0.4, and save .xlsx file
%            exportdatafilename
%            scoreCSVwrite(outputfilefullname,handles)

            % from PostAnalGUI
                        % Read N-glycan List and N-glycopeptide List from input file
            fileID = fopen(string(app.NglyDigestTextArea.Value), 'r');

            % Check if the file was opened successfully
            if fileID == -1
                disp('Error: Could not open the file.');
                return;
            end
            lines = {};
            % Read the content of the file as a character array
            while ~feof(fileID)
                line = fgetl(fileID);
                lines = [lines; {line}];
            end
            matchingIndices = startsWith(lines, '#');   % find lines starting with in order to parse glycans and peptides
            idx=find(matchingIndices);
            temp = lines(idx(6)+2:idx(7)-1);
            NglyList=cell(length(temp),1);              % get rid of prefix and suffix from glycan list
            for i = 1: length(temp)
                idx2=strfind(temp{i},' ');
                NglyList{i} = {temp{i}(idx2(2)+1:idx2(3)-1)};
            end
            NpepList = lines(idx(13)+2:idx(14)-1);
            for i = 1: length(NpepList)                 % get rid of elements in {2} from peptide list
                      NpepList{i}={strrep(NpepList{i},'{2}','')};   
            end
            % Close the file
            fclose(fileID);

            % Read O-glycan List and O-glycopeptide List from input file
            fileID = fopen(string(app.OglyDigestTextArea.Value), 'r');
            if fileID == -1            % Check if the file was opened successfully
                disp('Error: Could not open the file.');
                return;
            end
            lines = {};
            while ~feof(fileID)        % Read the content of the file as a character array
                line = fgetl(fileID);
                lines = [lines; {line}];
            end
            matchingIndices = startsWith(lines, '#');   % find lines starting with in order to parse glycans and peptides
            idx=find(matchingIndices);
            temp = lines(idx(6)+2:idx(7)-1);
            OglyList=cell(length(temp),1);              % get rid of prefix and suffix from glycan list
            for i = 1: length(temp)
                idx2=strfind(temp{i},' ');
                OglyList{i} = temp{i}(idx2(2)+1:idx2(3)-1);
            end
            OpepList = lines(idx(13)+2:idx(14)-1);
            for i = 1: length(OpepList)                 % get rid of elements in {2} from peptide list
                      OpepList{i}=strrep(OpepList{i},'{2}','');   
            end
            OpepList = unique(OpepList);
            fclose(fileID);                 % Close the file
            
            OutputFolder = app.OutputfolderlocationTextArea.Value;
            Out_Loc = app.ScoredNglypepFileTextArea.Value;
            Out_pep = readtable(string(app.ScoredPepFileTextArea.Value));
            Out_N = readtable(string(app.ScoredNglypepFileTextArea.Value));
            Out_O = readtable(string(app.ScoredOglypepFileTextArea.Value));
            StatusTextAreaValueChanged(app, 'Analyzing N-glycosylation data... (starting parallel computing)')
            postAnalN(NpepList,NglyList,Out_pep,Out_N,OutputFolder,Out_Loc) %NpepList,NglyList,Out_pep,Out_N,Output Folder,NGlyInputFile
            StatusTextAreaValueChanged(app, 'Analyzing O-glycosylation data...')
            postAnalO(OpepList,OglyList,Out_pep,Out_O,OutputFolder,Out_Loc)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.synglycanfig = uifigure('Visible', 'off');
            colormap(app.synglycanfig, 'parula');
            app.synglycanfig.Name = 'SynGlycan Analysis';
            app.scoreall = uipanel(app.synglycanfig);
            app.scoreall.Title = 'SynGlycan Analysis';
            app.inputs = uipanel(app.scoreall);
            app.inputs.Title = 'Inputs';
            app.uipanel_msdataload = uibuttongroup(app.inputs);
            app.uipanel_msdataload.Title = '4. Load MS Data (accepts .RAW, .mzML, or .mat files)';
            app.pushbutton_selectmatfile = uibutton(app.uipanel_msdataload, 'push');
            app.pushbutton_selectmatfile.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_selectmatfileButtonPushed, true);
            app.pushbutton_selectmatfile.Text = {'Select an Experimental'; 'Data File'};
            app.edit_loadmatstatus = uitextarea(app.uipanel_msdataload);
            app.edit_loadmatstatus.ValueChangedFcn = createCallbackFcn(app, @edit_loadmatstatusValueChanged, true);
            app.SelectMassTolerancesPanel = uipanel(app.inputs);
            app.SelectMassTolerancesPanel.Title = '7. Select Mass Tolerances';
            app.CIDunits = uidropdown(app.SelectMassTolerancesPanel);
            app.CIDunits.Items = {'ppm', 'Da'};
            app.CIDunits.Value = 'Da';
            app.CIDMS2ToleranceLabel = uilabel(app.SelectMassTolerancesPanel);
            app.CIDMS2ToleranceLabel.Text = 'CID MS2 Tolerance:';
            app.CID = uieditfield(app.SelectMassTolerancesPanel, 'numeric');
            app.CID.Value = 0.4;
            app.HCDunits = uidropdown(app.SelectMassTolerancesPanel);
            app.HCDunits.Items = {'ppm', 'Da'};
            app.HCDunits.Value = 'ppm';
            app.HCDMS2ToleranceLabel = uilabel(app.SelectMassTolerancesPanel);
            app.HCDMS2ToleranceLabel.Text = 'HCD MS2 Tolerance:';
            app.HCD = uieditfield(app.SelectMassTolerancesPanel, 'numeric');
            app.HCD.Value = 20;
            app.EThcDunits = uidropdown(app.SelectMassTolerancesPanel);
            app.EThcDunits.Items = {'ppm', 'Da'};
            app.EThcDunits.Value = 'ppm';
            app.ETDMS2ToleranceLabel = uilabel(app.SelectMassTolerancesPanel);
            app.ETDMS2ToleranceLabel.Text = 'EThcD MS2 Tolerance:';
            app.EThcD = uieditfield(app.SelectMassTolerancesPanel, 'numeric');
            app.EThcD.Value = 20;
            app.CIDMS2ToleranceLabel_2 = uilabel(app.SelectMassTolerancesPanel);
            app.CIDMS2ToleranceLabel_2.Text = 'MS1 Tolerance:';
            app.MS1 = uieditfield(app.SelectMassTolerancesPanel, 'numeric');
            app.MS1.Value = 5;
            app.MS1units = uidropdown(app.SelectMassTolerancesPanel);
            app.MS1units.Items = {'ppm', 'Da'};
            app.MS1units.Value = 'ppm';
            app.SelectFixedPTMPanel = uipanel(app.inputs);
            app.SelectFixedPTMPanel.Title = '2. Select Fixed PTM';
            app.FixedPTMNoneCheckBox = uicheckbox(app.SelectFixedPTMPanel);
            app.FixedPTMNoneCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedPTMNoneCheckBoxValueChanged, true);
            app.FixedPTMNoneCheckBox.Text = 'None';
            app.FixedPTMCysteineCarboxymethylationCheckBox = uicheckbox(app.SelectFixedPTMPanel);
            app.FixedPTMCysteineCarboxymethylationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedPTMCysteineCarboxymethylationCheckBoxValueChanged, true);
            app.FixedPTMCysteineCarboxymethylationCheckBox.Text = 'Cysteine Carboxymethylation';
            app.FixedPTMCysteineCarboxymethylationCheckBox.Value = true;
            app.LoadaFASTAfilecontainingtheproteinsequencePanel = uipanel(app.inputs);
            app.LoadaFASTAfilecontainingtheproteinsequencePanel.Title = '1. Load a FASTA file containing the protein sequence.';
            app.pushbutton_selectglylibfile = uibutton(app.LoadaFASTAfilecontainingtheproteinsequencePanel, 'push');
            app.pushbutton_selectglylibfile.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_selectglylibfileButtonPushed, true);
            app.pushbutton_selectglylibfile.Text = 'Select a File';
            app.edit_loadedfastafile = uitextarea(app.LoadaFASTAfilecontainingtheproteinsequencePanel);
            app.edit_loadedfastafile.ValueChangedFcn = createCallbackFcn(app, @edit_loadedfastafileValueChanged, true);
            app.SelectOutputFileLocationPanel = uipanel(app.inputs);
            app.SelectOutputFileLocationPanel.Title = '3. Select Output File Location';
            app.pushbutton_outputfilename = uibutton(app.SelectOutputFileLocationPanel, 'push');
            app.pushbutton_outputfilename.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_outputfilenameButtonPushed, true);
            app.pushbutton_outputfilename.Text = 'Save Location';
            app.edit_outputfilename = uitextarea(app.SelectOutputFileLocationPanel);
            app.edit_outputfilename.ValueChangedFcn = createCallbackFcn(app, @edit_outputfilenameValueChanged, true);
            app.SelectProteasePanel = uipanel(app.inputs);
            app.SelectProteasePanel.Title = '5. Select Protease';
            app.EnzymeDropDownLabel = uilabel(app.SelectProteasePanel);
            app.EnzymeDropDownLabel.Text = 'Enzyme';
            app.EnzymeDropDown = uidropdown(app.SelectProteasePanel);
            app.EnzymeDropDown.Items = {'Trypsin', 'Glu-C(V8 DE)', 'Glu-C(V8 E)', 'Lys-C', 'CNBR', 'Chymotrypsin High', 'Chymotrypsin Low', 'Trypsin & Glu-C(V8 DE)', 'Trypsin & Glu-C(V8 E)', 'Trypsin & Lys-C', 'Trypsin & CNBR', 'Trypsin & Chymotrypsin High', 'Trypsin & Chymotrypsin Low', 'Glu-C(V8 DE) & Lys-C', 'Glu-C(V8 DE) & CNBR', 'Glu-C(V8 DE) & Chymotrypsin High', 'Glu-C(V8 DE) & Chymotrypsin Low', 'Glu-C(V8 E) & Lys-C', 'Glu-C(V8 E) & CNBR', 'Glu-C(V8 E) & Chymotrypsin High', 'Glu-C(V8 E) & Chymotrypsin Low', 'Lys-C & CNBR', 'Lys-C & Chymotrypsin High', 'Lys-C & Chymotrypsin Low', 'CNBR & Chymotrypsin High', 'CNBR & Chymotrypsin Low'};
            app.EnzymeDropDown.Value = 'Trypsin';
            app.SelectGlycanLibraryPanel = uipanel(app.inputs);
            app.SelectGlycanLibraryPanel.Title = '6. Select Glycan Library';
            app.NGlycanDropDownLabel = uilabel(app.SelectGlycanLibraryPanel);
            app.NGlycanDropDownLabel.Text = 'N-Glycan';
            app.NGlycanDropDown = uidropdown(app.SelectGlycanLibraryPanel);
            app.OGlycanDropDownLabel = uilabel(app.SelectGlycanLibraryPanel);
            app.OGlycanDropDownLabel.Text = 'O-Glycan';
            app.OGlycanDropDown = uidropdown(app.SelectGlycanLibraryPanel);
            app.pushbutton_scoreallfiles = uibutton(app.scoreall, 'push');
            app.pushbutton_scoreallfiles.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_scoreallfilesButtonPushed, true);
            app.pushbutton_scoreallfiles.Text = '1. SCORE';
            app.StatusPanel = uipanel(app.scoreall);
            app.StatusPanel.Title = 'Status Panel';
            app.StatusTextArea = uitextarea(app.StatusPanel);
            app.pushbutton_browseresults_2 = uibutton(app.scoreall, 'push');
            app.pushbutton_browseresults_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_browseresults_2ButtonPushed, true);
            app.pushbutton_browseresults_2.Text = '2. View Results';
            app.PostAnalysisButton = uibutton(app.scoreall, 'push');
            app.PostAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PostAnalysisButtonPushed, true);
            app.PostAnalysisButton.Text = '3. Post-Analysis';
            app.Label = uilabel(app.scoreall);
            app.Label.Text = '---';
            app.Label_2 = uilabel(app.scoreall);
            app.Label_2.Text = '>';
            app.Label_3 = uilabel(app.scoreall);
            app.Label_3.Text = '---';
            app.Label_4 = uilabel(app.scoreall);
            app.Label_4.Text = '>';
            % Show the figure after all components are created
            app.synglycanfig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synglycangui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.synglycanfig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.synglycanfig)
        end
    end
end